classdef RoboVizApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        CLOSEButton                  matlab.ui.control.Button
        WorkEnvelopeButton_2         matlab.ui.control.Button
        PlotROBOTButton_2            matlab.ui.control.Button
        PushButton_2                 matlab.ui.control.Button
        ButtonGroup                  matlab.ui.container.ButtonGroup
        ChooseRobotButton            matlab.ui.control.RadioButton
        EnterDOFButton               matlab.ui.control.RadioButton
        ChooseRobotDropDown          matlab.ui.control.DropDown
        ChooseRobotDropDownLabel     matlab.ui.control.Label
        d10Spinner                   matlab.ui.control.Spinner
        d10SpinnerLabel              matlab.ui.control.Label
        d9Spinner                    matlab.ui.control.Spinner
        d9SpinnerLabel               matlab.ui.control.Label
        d8Spinner                    matlab.ui.control.Spinner
        d8SpinnerLabel               matlab.ui.control.Label
        d7Spinner                    matlab.ui.control.Spinner
        d7SpinnerLabel               matlab.ui.control.Label
        d6Spinner                    matlab.ui.control.Spinner
        d6SpinnerLabel               matlab.ui.control.Label
        d5Spinner                    matlab.ui.control.Spinner
        d5SpinnerLabel               matlab.ui.control.Label
        d4Spinner                    matlab.ui.control.Spinner
        d4SpinnerLabel               matlab.ui.control.Label
        d3Spinner                    matlab.ui.control.Spinner
        d3SpinnerLabel               matlab.ui.control.Label
        d2Spinner                    matlab.ui.control.Spinner
        d2SpinnerLabel               matlab.ui.control.Label
        JointVariable                matlab.ui.control.Label
        d1Spinner                    matlab.ui.control.Spinner
        d1SpinnerLabel               matlab.ui.control.Label
        EndEffectorOrientationLabel  matlab.ui.control.Label
        UITable2                     matlab.ui.control.Table
        WorkEnvelopeButton           matlab.ui.control.Button
        EndEffectorCoordinatesPanel  matlab.ui.container.Panel
        ZEditField                   matlab.ui.control.NumericEditField
        ZEditFieldLabel              matlab.ui.control.Label
        YEditField                   matlab.ui.control.NumericEditField
        YEditFieldLabel              matlab.ui.control.Label
        XEditField                   matlab.ui.control.NumericEditField
        XEditFieldLabel              matlab.ui.control.Label
        Joint_10Type                 matlab.ui.control.DropDown
        Joint_9Type                  matlab.ui.control.DropDown
        Joint_8Type                  matlab.ui.control.DropDown
        Joint_7Type                  matlab.ui.control.DropDown
        Joint_6Type                  matlab.ui.control.DropDown
        Joint_5Type                  matlab.ui.control.DropDown
        Joint_4Type                  matlab.ui.control.DropDown
        Joint_3Type                  matlab.ui.control.DropDown
        PlotROBOTButton              matlab.ui.control.Button
        Joint_2Type                  matlab.ui.control.DropDown
        Joint_1Type                  matlab.ui.control.DropDown
        JointTypeDropDownLabel       matlab.ui.control.Label
        EnterDOFDropDown             matlab.ui.control.DropDown
        EnterDOFDropDownLabel        matlab.ui.control.Label
        PushButton                   matlab.ui.control.Button
        UITable                      matlab.ui.control.Table
        UIAxes                       matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            app.Joint_1Type.Visible='off';
            app.Joint_2Type.Visible = 'off';
            app.Joint_3Type.Visible = 'off';
            app.Joint_4Type.Visible = 'off';
            app.Joint_5Type.Visible = 'off';
            app.Joint_6Type.Visible = 'off';
            app.Joint_7Type.Visible = 'off';
            app.Joint_8Type.Visible = 'off';
            app.Joint_9Type.Visible = 'off';
            app.Joint_10Type.Visible = 'off';
            %             app.JointVariable.Visible ='off';
            app.d1Spinner.Visible = 'off'; app.d1SpinnerLabel.Visible = 'off';
            app.d2Spinner.Visible = 'off'; app.d2SpinnerLabel.Visible = 'off';
            app.d3Spinner.Visible = 'off'; app.d3SpinnerLabel.Visible = 'off';
            app.d4Spinner.Visible = 'off'; app.d4SpinnerLabel.Visible = 'off';
            app.d5Spinner.Visible = 'off'; app.d5SpinnerLabel.Visible = 'off';
            app.d6Spinner.Visible = 'off'; app.d6SpinnerLabel.Visible = 'off';
            app.d7Spinner.Visible = 'off'; app.d7SpinnerLabel.Visible = 'off';
            app.d8Spinner.Visible = 'off'; app.d8SpinnerLabel.Visible = 'off';
            app.d9Spinner.Visible = 'off'; app.d9SpinnerLabel.Visible = 'off';
            app.d10Spinner.Visible = 'off'; app.d10SpinnerLabel.Visible = 'off';

            app.PlotROBOTButton.Visible = 'on';

            app.EnterDOFDropDown.Visible = 'off';
            app.EnterDOFDropDownLabel.Visible = 'off';
            app.ChooseRobotDropDown.Visible = 'off';
            app.ChooseRobotDropDownLabel.Visible = 'off';
            app.PushButton.Visible = 'off';
            app.PushButton_2.Visible = 'off';


            title(app.UIAxes,'');
        end

        % Button pushed function: PushButton
        function PushButtonPushed(app, event)

            dof = app.EnterDOFDropDown.Value;

            if dof=='2'
                DHTab = [0 0 0 0;0 0 0 0];
                app.UITable.Data = DHTab;
                app.UITable.RowName = {'Joint-1','Joint-2'};
                app.UITable.ColumnName = {'Theta','d','a','Alpha'};
                EE_COR = [0 0 0];
                app.XEditField.Value = EE_COR(1,1);
                app.YEditField.Value = EE_COR(1,2);
                app.ZEditField.Value = EE_COR(1,3);
                Or_Mat = [0 0 0;0 0 0;0 0 0];
                app.UITable2.Data = Or_Mat;
                cla(app.UIAxes) 
                
                app.Joint_1Type.Visible='on';
                app.Joint_2Type.Visible = 'on';
                app.Joint_3Type.Visible = 'off';
                app.Joint_4Type.Visible = 'off';
                app.Joint_5Type.Visible = 'off';
                app.Joint_6Type.Visible = 'off';
                app.Joint_7Type.Visible = 'off';
                app.Joint_8Type.Visible = 'off';
                app.Joint_9Type.Visible = 'off';
                app.Joint_10Type.Visible = 'off';

                app.d1Spinner.Visible = 'off'; app.d1SpinnerLabel.Visible = 'off';    app.d1Spinner.Value=0;
                app.d2Spinner.Visible = 'off'; app.d2SpinnerLabel.Visible = 'off';    app.d2Spinner.Value=0;
                app.d3Spinner.Visible = 'off'; app.d3SpinnerLabel.Visible = 'off';
                app.d4Spinner.Visible = 'off'; app.d4SpinnerLabel.Visible = 'off';
                app.d5Spinner.Visible = 'off'; app.d5SpinnerLabel.Visible = 'off';
                app.d6Spinner.Visible = 'off'; app.d6SpinnerLabel.Visible = 'off';
                app.d7Spinner.Visible = 'off'; app.d7SpinnerLabel.Visible = 'off';
                app.d8Spinner.Visible = 'off'; app.d8SpinnerLabel.Visible = 'off';
                app.d9Spinner.Visible = 'off'; app.d9SpinnerLabel.Visible = 'off';
                app.d10Spinner.Visible = 'off'; app.d10SpinnerLabel.Visible = 'off';

                app.Joint_1Type.Value = "Revolute";
                app.Joint_2Type.Value = "Revolute";
                app.Joint_3Type.Value = "Revolute";
                app.Joint_4Type.Value = "Revolute";
                app.Joint_5Type.Value = "Revolute";
                app.Joint_6Type.Value = "Revolute";
                app.Joint_7Type.Value = "Revolute";
                app.Joint_8Type.Value = "Revolute";
                app.Joint_9Type.Value = "Revolute";
                app.Joint_10Type.Value = "Revolute";

            elseif dof=='3'

                DHTab = [0 0 0 0;0 0 0 0;0 0 0 0];
                app.UITable.Data = DHTab;
                app.UITable.RowName = {'Joint-1','Joint-2','Joint-3'};
                app.UITable.ColumnName = {'Theta','d','a','Alpha'};
                EE_COR = [0 0 0];
                app.XEditField.Value = EE_COR(1,1);
                app.YEditField.Value = EE_COR(1,2);
                app.ZEditField.Value = EE_COR(1,3);
                Or_Mat = [0 0 0;0 0 0;0 0 0];
                app.UITable2.Data = Or_Mat;
                cla(app.UIAxes) 
                
                app.Joint_1Type.Visible='on';
                app.Joint_2Type.Visible = 'on';
                app.Joint_3Type.Visible = 'on';
                app.Joint_4Type.Visible = 'off';
                app.Joint_5Type.Visible = 'off';
                app.Joint_6Type.Visible = 'off';
                app.Joint_7Type.Visible = 'off';
                app.Joint_8Type.Visible = 'off';
                app.Joint_9Type.Visible = 'off';
                app.Joint_10Type.Visible = 'off';

                app.d1Spinner.Visible = 'off'; app.d1SpinnerLabel.Visible = 'off';  app.d1Spinner.Value=0;
                app.d2Spinner.Visible = 'off'; app.d2SpinnerLabel.Visible = 'off';  app.d2Spinner.Value=0;
                app.d3Spinner.Visible = 'off'; app.d3SpinnerLabel.Visible = 'off';  app.d3Spinner.Value=0;
                app.d4Spinner.Visible = 'off'; app.d4SpinnerLabel.Visible = 'off';
                app.d5Spinner.Visible = 'off'; app.d5SpinnerLabel.Visible = 'off';
                app.d6Spinner.Visible = 'off'; app.d6SpinnerLabel.Visible = 'off';
                app.d7Spinner.Visible = 'off'; app.d7SpinnerLabel.Visible = 'off';
                app.d8Spinner.Visible = 'off'; app.d8SpinnerLabel.Visible = 'off';
                app.d9Spinner.Visible = 'off'; app.d9SpinnerLabel.Visible = 'off';
                app.d10Spinner.Visible = 'off'; app.d10SpinnerLabel.Visible = 'off';

                app.Joint_1Type.Value = "Revolute";
                app.Joint_2Type.Value = "Revolute";
                app.Joint_3Type.Value = "Revolute";
                app.Joint_4Type.Value = "Revolute";
                app.Joint_5Type.Value = "Revolute";
                app.Joint_6Type.Value = "Revolute";
                app.Joint_7Type.Value = "Revolute";
                app.Joint_8Type.Value = "Revolute";
                app.Joint_9Type.Value = "Revolute";
                app.Joint_10Type.Value = "Revolute";

            elseif dof=='4'

                DHTab = [0 0 0 0;0 0 0 0;0 0 0 0;0 0 0 0];
                app.UITable.Data = DHTab;
                app.UITable.RowName = {'Joint-1','Joint-2','Joint-3','Joint-4'};
                app.UITable.ColumnName = {'Theta','d','a','Alpha'};
                EE_COR = [0 0 0];
                app.XEditField.Value = EE_COR(1,1);
                app.YEditField.Value = EE_COR(1,2);
                app.ZEditField.Value = EE_COR(1,3);
                Or_Mat = [0 0 0;0 0 0;0 0 0];
                app.UITable2.Data = Or_Mat;
                cla(app.UIAxes) 
                
                app.Joint_1Type.Visible='on';
                app.Joint_2Type.Visible = 'on';
                app.Joint_3Type.Visible = 'on';
                app.Joint_4Type.Visible = 'on';
                app.Joint_5Type.Visible = 'off';
                app.Joint_6Type.Visible = 'off';
                app.Joint_7Type.Visible = 'off';
                app.Joint_8Type.Visible = 'off';
                app.Joint_9Type.Visible = 'off';
                app.Joint_10Type.Visible = 'off';

                app.d1Spinner.Visible = 'off'; app.d1SpinnerLabel.Visible = 'off';  app.d1Spinner.Value=0;
                app.d2Spinner.Visible = 'off'; app.d2SpinnerLabel.Visible = 'off';  app.d2Spinner.Value=0;
                app.d3Spinner.Visible = 'off'; app.d3SpinnerLabel.Visible = 'off';  app.d3Spinner.Value=0;
                app.d4Spinner.Visible = 'off'; app.d4SpinnerLabel.Visible = 'off';  app.d4Spinner.Value=0;
                app.d5Spinner.Visible = 'off'; app.d5SpinnerLabel.Visible = 'off';
                app.d6Spinner.Visible = 'off'; app.d6SpinnerLabel.Visible = 'off';
                app.d7Spinner.Visible = 'off'; app.d7SpinnerLabel.Visible = 'off';
                app.d8Spinner.Visible = 'off'; app.d8SpinnerLabel.Visible = 'off';
                app.d9Spinner.Visible = 'off'; app.d9SpinnerLabel.Visible = 'off';
                app.d10Spinner.Visible = 'off'; app.d10SpinnerLabel.Visible = 'off';

                app.Joint_1Type.Value = "Revolute";
                app.Joint_2Type.Value = "Revolute";
                app.Joint_3Type.Value = "Revolute";
                app.Joint_4Type.Value = "Revolute";
                app.Joint_5Type.Value = "Revolute";
                app.Joint_6Type.Value = "Revolute";
                app.Joint_7Type.Value = "Revolute";
                app.Joint_8Type.Value = "Revolute";
                app.Joint_9Type.Value = "Revolute";
                app.Joint_10Type.Value = "Revolute";

            elseif dof=='5'

                DHTab = [0 0 0 0;0 0 0 0;0 0 0 0;0 0 0 0;0 0 0 0];
                app.UITable.Data = DHTab;
                app.UITable.RowName = {'Joint-1','Joint-2','Joint-3','Joint-4','Joint-5'};
                app.UITable.ColumnName = {'Theta','d','a','Alpha'};
                EE_COR = [0 0 0];
                app.XEditField.Value = EE_COR(1,1);
                app.YEditField.Value = EE_COR(1,2);
                app.ZEditField.Value = EE_COR(1,3);
                Or_Mat = [0 0 0;0 0 0;0 0 0];
                app.UITable2.Data = Or_Mat;
                cla(app.UIAxes) 
                
                app.Joint_1Type.Visible='on';
                app.Joint_2Type.Visible = 'on';
                app.Joint_3Type.Visible = 'on';
                app.Joint_4Type.Visible = 'on';
                app.Joint_5Type.Visible = 'on';
                app.Joint_6Type.Visible = 'off';
                app.Joint_7Type.Visible = 'off';
                app.Joint_8Type.Visible = 'off';
                app.Joint_9Type.Visible = 'off';
                app.Joint_10Type.Visible = 'off';

                app.d1Spinner.Visible = 'off'; app.d1SpinnerLabel.Visible = 'off'; app.d1Spinner.Value=0;
                app.d2Spinner.Visible = 'off'; app.d2SpinnerLabel.Visible = 'off'; app.d2Spinner.Value=0;
                app.d3Spinner.Visible = 'off'; app.d3SpinnerLabel.Visible = 'off'; app.d3Spinner.Value=0;
                app.d4Spinner.Visible = 'off'; app.d4SpinnerLabel.Visible = 'off'; app.d4Spinner.Value=0;
                app.d5Spinner.Visible = 'off'; app.d5SpinnerLabel.Visible = 'off'; app.d5Spinner.Value=0;
                app.d6Spinner.Visible = 'off'; app.d6SpinnerLabel.Visible = 'off'; 
                app.d7Spinner.Visible = 'off'; app.d7SpinnerLabel.Visible = 'off';
                app.d8Spinner.Visible = 'off'; app.d8SpinnerLabel.Visible = 'off';
                app.d9Spinner.Visible = 'off'; app.d9SpinnerLabel.Visible = 'off';
                app.d10Spinner.Visible = 'off'; app.d10SpinnerLabel.Visible = 'off';

                app.Joint_1Type.Value = "Revolute";
                app.Joint_2Type.Value = "Revolute";
                app.Joint_3Type.Value = "Revolute";
                app.Joint_4Type.Value = "Revolute";
                app.Joint_5Type.Value = "Revolute";
                app.Joint_6Type.Value = "Revolute";
                app.Joint_7Type.Value = "Revolute";
                app.Joint_8Type.Value = "Revolute";
                app.Joint_9Type.Value = "Revolute";
                app.Joint_10Type.Value = "Revolute";

            elseif dof=='6'

                DHTab = [0 0 0 0;0 0 0 0;0 0 0 0;0 0 0 0;0 0 0 0;0 0 0 0];
                app.UITable.Data = DHTab;
                app.UITable.RowName = {'Joint-1','Joint-2','Joint-3','Joint-4','Joint-5','Joint-6'};
                app.UITable.ColumnName = {'Theta','d','a','Alpha'};
                EE_COR = [0 0 0];
                app.XEditField.Value = EE_COR(1,1);
                app.YEditField.Value = EE_COR(1,2);
                app.ZEditField.Value = EE_COR(1,3);
                Or_Mat = [0 0 0;0 0 0;0 0 0];
                app.UITable2.Data = Or_Mat;
                cla(app.UIAxes) 
                
                app.Joint_1Type.Visible='on';
                app.Joint_2Type.Visible = 'on';
                app.Joint_3Type.Visible = 'on';
                app.Joint_4Type.Visible = 'on';
                app.Joint_5Type.Visible = 'on';
                app.Joint_6Type.Visible = 'on';
                app.Joint_7Type.Visible = 'off';
                app.Joint_8Type.Visible = 'off';
                app.Joint_9Type.Visible = 'off';
                app.Joint_10Type.Visible = 'off';

                app.d1Spinner.Visible = 'off'; app.d1SpinnerLabel.Visible = 'off'; app.d1Spinner.Value=0;
                app.d2Spinner.Visible = 'off'; app.d2SpinnerLabel.Visible = 'off'; app.d2Spinner.Value=0;
                app.d3Spinner.Visible = 'off'; app.d3SpinnerLabel.Visible = 'off'; app.d3Spinner.Value=0;
                app.d4Spinner.Visible = 'off'; app.d4SpinnerLabel.Visible = 'off'; app.d4Spinner.Value=0;
                app.d5Spinner.Visible = 'off'; app.d5SpinnerLabel.Visible = 'off'; app.d5Spinner.Value=0;
                app.d6Spinner.Visible = 'off'; app.d6SpinnerLabel.Visible = 'off'; app.d6Spinner.Value=0;
                app.d7Spinner.Visible = 'off'; app.d7SpinnerLabel.Visible = 'off';
                app.d8Spinner.Visible = 'off'; app.d8SpinnerLabel.Visible = 'off';
                app.d9Spinner.Visible = 'off'; app.d9SpinnerLabel.Visible = 'off';
                app.d10Spinner.Visible = 'off'; app.d10SpinnerLabel.Visible = 'off';

                app.Joint_1Type.Value = "Revolute";
                app.Joint_2Type.Value = "Revolute";
                app.Joint_3Type.Value = "Revolute";
                app.Joint_4Type.Value = "Revolute";
                app.Joint_5Type.Value = "Revolute";
                app.Joint_6Type.Value = "Revolute";
                app.Joint_7Type.Value = "Revolute";
                app.Joint_8Type.Value = "Revolute";
                app.Joint_9Type.Value = "Revolute";
                app.Joint_10Type.Value = "Revolute";

            elseif dof=='7'

                DHTab = [0 0 0 0;0 0 0 0;0 0 0 0;0 0 0 0;0 0 0 0;0 0 0 0;0 0 0 0];
                app.UITable.Data = DHTab;
                app.UITable.RowName = {'Joint-1','Joint-2','Joint-3','Joint-4','Joint-5','Joint-6','Joint-7'};
                app.UITable.ColumnName = {'Theta','d','a','Alpha'};
                EE_COR = [0 0 0];
                app.XEditField.Value = EE_COR(1,1);
                app.YEditField.Value = EE_COR(1,2);
                app.ZEditField.Value = EE_COR(1,3);
                Or_Mat = [0 0 0;0 0 0;0 0 0];
                app.UITable2.Data = Or_Mat;
                cla(app.UIAxes) 
                
                app.Joint_1Type.Visible='on';
                app.Joint_2Type.Visible = 'on';
                app.Joint_3Type.Visible = 'on';
                app.Joint_4Type.Visible = 'on';
                app.Joint_5Type.Visible = 'on';
                app.Joint_6Type.Visible = 'on';
                app.Joint_7Type.Visible = 'on';
                app.Joint_8Type.Visible = 'off';
                app.Joint_9Type.Visible = 'off';
                app.Joint_10Type.Visible = 'off';

                app.d1Spinner.Visible = 'off'; app.d1SpinnerLabel.Visible = 'off';  app.d1Spinner.Value=0;
                app.d2Spinner.Visible = 'off'; app.d2SpinnerLabel.Visible = 'off';  app.d2Spinner.Value=0;
                app.d3Spinner.Visible = 'off'; app.d3SpinnerLabel.Visible = 'off';  app.d3Spinner.Value=0;
                app.d4Spinner.Visible = 'off'; app.d4SpinnerLabel.Visible = 'off';  app.d4Spinner.Value=0;
                app.d5Spinner.Visible = 'off'; app.d5SpinnerLabel.Visible = 'off';  app.d5Spinner.Value=0;
                app.d6Spinner.Visible = 'off'; app.d6SpinnerLabel.Visible = 'off';  app.d6Spinner.Value=0;
                app.d7Spinner.Visible = 'off'; app.d7SpinnerLabel.Visible = 'off';  app.d7Spinner.Value=0;
                app.d8Spinner.Visible = 'off'; app.d8SpinnerLabel.Visible = 'off';
                app.d9Spinner.Visible = 'off'; app.d9SpinnerLabel.Visible = 'off';
                app.d10Spinner.Visible = 'off'; app.d10SpinnerLabel.Visible = 'off';

                app.Joint_1Type.Value = "Revolute";
                app.Joint_2Type.Value = "Revolute";
                app.Joint_3Type.Value = "Revolute";
                app.Joint_4Type.Value = "Revolute";
                app.Joint_5Type.Value = "Revolute";
                app.Joint_6Type.Value = "Revolute";
                app.Joint_7Type.Value = "Revolute";
                app.Joint_8Type.Value = "Revolute";
                app.Joint_9Type.Value = "Revolute";
                app.Joint_10Type.Value = "Revolute";

            elseif dof=='8'
                DHTab = [0 0 0 0;0 0 0 0;0 0 0 0;0 0 0 0;0 0 0 0;0 0 0 0;0 0 0 0;0 0 0 0];
                app.UITable.Data = DHTab;
                app.UITable.RowName = {'Joint-1','Joint-2','Joint-3','Joint-4','Joint-5','Joint-6','Joint-7','Joint-8'};
                app.UITable.ColumnName = {'Theta','d','a','Alpha'};
                EE_COR = [0 0 0];
                app.XEditField.Value = EE_COR(1,1);
                app.YEditField.Value = EE_COR(1,2);
                app.ZEditField.Value = EE_COR(1,3);
                Or_Mat = [0 0 0;0 0 0;0 0 0];
                app.UITable2.Data = Or_Mat;
                cla(app.UIAxes) 
                
                app.Joint_1Type.Visible='on';
                app.Joint_2Type.Visible = 'on';
                app.Joint_3Type.Visible = 'on';
                app.Joint_4Type.Visible = 'on';
                app.Joint_5Type.Visible = 'on';
                app.Joint_6Type.Visible = 'on';
                app.Joint_7Type.Visible = 'on';
                app.Joint_8Type.Visible = 'on';
                app.Joint_9Type.Visible = 'off';
                app.Joint_10Type.Visible = 'off';

                app.d1Spinner.Visible = 'off'; app.d1SpinnerLabel.Visible = 'off'; app.d1Spinner.Value=0;
                app.d2Spinner.Visible = 'off'; app.d2SpinnerLabel.Visible = 'off'; app.d2Spinner.Value=0;
                app.d3Spinner.Visible = 'off'; app.d3SpinnerLabel.Visible = 'off'; app.d3Spinner.Value=0;
                app.d4Spinner.Visible = 'off'; app.d4SpinnerLabel.Visible = 'off'; app.d4Spinner.Value=0;
                app.d5Spinner.Visible = 'off'; app.d5SpinnerLabel.Visible = 'off'; app.d5Spinner.Value=0;
                app.d6Spinner.Visible = 'off'; app.d6SpinnerLabel.Visible = 'off'; app.d6Spinner.Value=0;
                app.d7Spinner.Visible = 'off'; app.d7SpinnerLabel.Visible = 'off'; app.d7Spinner.Value=0;
                app.d8Spinner.Visible = 'off'; app.d8SpinnerLabel.Visible = 'off'; app.d8Spinner.Value=0;
                app.d9Spinner.Visible = 'off'; app.d9SpinnerLabel.Visible = 'off';
                app.d10Spinner.Visible = 'off'; app.d10SpinnerLabel.Visible = 'off';

                app.Joint_1Type.Value = "Revolute";
                app.Joint_2Type.Value = "Revolute";
                app.Joint_3Type.Value = "Revolute";
                app.Joint_4Type.Value = "Revolute";
                app.Joint_5Type.Value = "Revolute";
                app.Joint_6Type.Value = "Revolute";
                app.Joint_7Type.Value = "Revolute";
                app.Joint_8Type.Value = "Revolute";
                app.Joint_9Type.Value = "Revolute";
                app.Joint_10Type.Value = "Revolute";

            elseif dof=='9'

                DHTab = [0 0 0 0;0 0 0 0;0 0 0 0;0 0 0 0;0 0 0 0;0 0 0 0;0 0 0 0;0 0 0 0;0 0 0 0];
                app.UITable.Data = DHTab;
                app.UITable.RowName = {'Joint-1','Joint-2','Joint-3','Joint-4','Joint-5','Joint-6','Joint-7','Joint-8','Joint-9'};
                app.UITable.ColumnName = {'Theta','d','a','Alpha'};
                EE_COR = [0 0 0];
                app.XEditField.Value = EE_COR(1,1);
                app.YEditField.Value = EE_COR(1,2);
                app.ZEditField.Value = EE_COR(1,3);
                Or_Mat = [0 0 0;0 0 0;0 0 0];
                app.UITable2.Data = Or_Mat;
                cla(app.UIAxes) 
                
                app.Joint_1Type.Visible='on';
                app.Joint_2Type.Visible = 'on';
                app.Joint_3Type.Visible = 'on';
                app.Joint_4Type.Visible = 'on';
                app.Joint_5Type.Visible = 'on';
                app.Joint_6Type.Visible = 'on';
                app.Joint_7Type.Visible = 'on';
                app.Joint_8Type.Visible = 'on';
                app.Joint_9Type.Visible = 'on';
                app.Joint_10Type.Visible = 'off';
                app.d1Spinner.Visible = 'off'; app.d1SpinnerLabel.Visible = 'off'; app.d1Spinner.Value=0;
                app.d2Spinner.Visible = 'off'; app.d2SpinnerLabel.Visible = 'off'; app.d2Spinner.Value=0;
                app.d3Spinner.Visible = 'off'; app.d3SpinnerLabel.Visible = 'off'; app.d3Spinner.Value=0;
                app.d4Spinner.Visible = 'off'; app.d4SpinnerLabel.Visible = 'off'; app.d4Spinner.Value=0;
                app.d5Spinner.Visible = 'off'; app.d5SpinnerLabel.Visible = 'off'; app.d5Spinner.Value=0;
                app.d6Spinner.Visible = 'off'; app.d6SpinnerLabel.Visible = 'off'; app.d6Spinner.Value=0;
                app.d7Spinner.Visible = 'off'; app.d7SpinnerLabel.Visible = 'off'; app.d7Spinner.Value=0;
                app.d8Spinner.Visible = 'off'; app.d8SpinnerLabel.Visible = 'off'; app.d8Spinner.Value=0;
                app.d9Spinner.Visible = 'off'; app.d9SpinnerLabel.Visible = 'off'; app.d9Spinner.Value=0;
                app.d10Spinner.Visible = 'off'; app.d10SpinnerLabel.Visible = 'off';

                app.Joint_1Type.Value = "Revolute";
                app.Joint_2Type.Value = "Revolute";
                app.Joint_3Type.Value = "Revolute";
                app.Joint_4Type.Value = "Revolute";
                app.Joint_5Type.Value = "Revolute";
                app.Joint_6Type.Value = "Revolute";
                app.Joint_7Type.Value = "Revolute";
                app.Joint_8Type.Value = "Revolute";
                app.Joint_9Type.Value = "Revolute";
                app.Joint_10Type.Value = "Revolute";

            elseif dof=="10"

                DHTab = [0 0 0 0;0 0 0 0;0 0 0 0;0 0 0 0;0 0 0 0;0 0 0 0;0 0 0 0;0 0 0 0;0 0 0 0;0 0 0 0];
                app.UITable.Data = DHTab;
                app.UITable.RowName = {'Joint-1','Joint-2','Joint-3','Joint-4','Joint-5','Joint-6','Joint-7','Joint-8','Joint-9','Joint-10'};
                app.UITable.ColumnName = {'Theta','d','a','Alpha'};
                EE_COR = [0 0 0];
                app.XEditField.Value = EE_COR(1,1);
                app.YEditField.Value = EE_COR(1,2);
                app.ZEditField.Value = EE_COR(1,3);
                Or_Mat = [0 0 0;0 0 0;0 0 0];
                app.UITable2.Data = Or_Mat;
                cla(app.UIAxes) 
                
                app.Joint_1Type.Visible = 'on'; app.d1Spinner.Value=0;
                app.Joint_2Type.Visible = 'on'; app.d2Spinner.Value=0;
                app.Joint_3Type.Visible = 'on'; app.d3Spinner.Value=0;
                app.Joint_4Type.Visible = 'on'; app.d4Spinner.Value=0;
                app.Joint_5Type.Visible = 'on'; app.d5Spinner.Value=0;
                app.Joint_6Type.Visible = 'on'; app.d6Spinner.Value=0;
                app.Joint_7Type.Visible = 'on'; app.d7Spinner.Value=0;
                app.Joint_8Type.Visible = 'on'; app.d8Spinner.Value=0;
                app.Joint_9Type.Visible = 'on'; app.d9Spinner.Value=0;
                app.Joint_10Type.Visible = 'on'; app.d10Spinner.Value=0;

                app.Joint_1Type.Value = "Revolute";
                app.Joint_2Type.Value = "Revolute";
                app.Joint_3Type.Value = "Revolute";
                app.Joint_4Type.Value = "Revolute";
                app.Joint_5Type.Value = "Revolute";
                app.Joint_6Type.Value = "Revolute";
                app.Joint_7Type.Value = "Revolute";
                app.Joint_8Type.Value = "Revolute";
                app.Joint_9Type.Value = "Revolute";
                app.Joint_10Type.Value = "Revolute";

            end

        end

        % Button pushed function: PlotROBOTButton
        function PlotROBOTButtonPushed(app, event)
            value1 = app.Joint_1Type.Value;
            value2 = app.Joint_2Type.Value;
            value3 = app.Joint_3Type.Value;
            value4 = app.Joint_4Type.Value;
            value5 = app.Joint_5Type.Value;
            value6 = app.Joint_6Type.Value;
            value7 = app.Joint_7Type.Value;
            value8 = app.Joint_8Type.Value;
            value9 = app.Joint_9Type.Value;
            value10 = app.Joint_10Type.Value;

            DOF = app.EnterDOFDropDown.Value;

            % DH parameters %%
            DHTab = app.UITable.Data;

            if DOF =='2'
                if (value1=="Prismatic")
                    V1 = app.d1Spinner.Value;
                    th1 = DHTab(1,1); D1 = DHTab(1,2)+V1; L1 = DHTab(1,3); al1 = DHTab(1,4);
                end
                if (value2=="Prismatic")
                    V2 = app.d2Spinner.Value;
                    th2 = DHTab(2,1); D2 = DHTab(2,2)+V2; L2 = DHTab(2,3); al2 = DHTab(2,4);
                end
                if (value1=="Revolute")
                    th1 = DHTab(1,1); D1 = DHTab(1,2); L1 = DHTab(1,3); al1 = DHTab(1,4);
                end
                if (value2=="Revolute")
                    th2 = DHTab(2,1); D2 = DHTab(2,2); L2 = DHTab(2,3); al2 = DHTab(2,4);
                end
                L3=0; D3=0; th3 = 0; al3 = 0;
                L4=0; D4=0; th4 = 0; al4 = 0;
                L5=0; D5=0; th5 = 0; al5 = 0;
                L6=0; D6=0; th6 = 0; al6 = 0;
                L7=0; D7=0; th7 = 0; al7 = 0;
                L8=0; D8=0; th8 = 0; al8 = 0;
                L9=0; D9=0; th9 = 0; al9 = 0;
                L10=0; D10=0; th10 = 0; al10= 0;
            elseif DOF =='3'

                if (value1=="Prismatic")
                    V1 = app.d1Spinner.Value;
                    th1 = DHTab(1,1); D1 = DHTab(1,2)+V1; L1 = DHTab(1,3); al1 = DHTab(1,4);
                end
                if (value2=="Prismatic")
                    V2 = app.d2Spinner.Value;
                    th2 = DHTab(2,1); D2 = DHTab(2,2)+V2; L2 = DHTab(2,3); al2 = DHTab(2,4);
                end
                if (value3=="Prismatic")
                    V3 = app.d3Spinner.Value;
                    th3 = DHTab(3,1); D3 = DHTab(3,2)+V3; L3 = DHTab(3,3); al3 = DHTab(3,4);
                end
                if (value1=="Revolute")
                    th1 = DHTab(1,1); D1 = DHTab(1,2); L1 = DHTab(1,3); al1 = DHTab(1,4);
                end
                if (value2=="Revolute")
                    th2 = DHTab(2,1); D2 = DHTab(2,2); L2 = DHTab(2,3); al2 = DHTab(2,4);
                end
                if (value3=="Revolute")
                    th3 = DHTab(3,1); D3 = DHTab(3,2); L3 = DHTab(3,3); al3 = DHTab(3,4);
                end
             
                L4=0; D4=0; th4 = 0; al4 = 0;
                L5=0; D5=0; th5 = 0; al5 = 0;
                L6=0; D6=0; th6 = 0; al6 = 0;
                L7=0; D7=0; th7 = 0; al7 = 0;
                L8=0; D8=0; th8 = 0; al8 = 0;
                L9=0; D9=0; th9 = 0; al9 = 0;
                L10=0; D10=0; th10 = 0; al10= 0;
            elseif DOF =='4'
                if (value1=="Prismatic")
                    V1 = app.d1Spinner.Value;
                    th1 = DHTab(1,1); D1 = DHTab(1,2)+V1; L1 = DHTab(1,3); al1 = DHTab(1,4);
                end
                if (value2=="Prismatic")
                    V2 = app.d2Spinner.Value;
                    th2 = DHTab(2,1); D2 = DHTab(2,2)+V2; L2 = DHTab(2,3); al2 = DHTab(2,4);
                end
                if (value3=="Prismatic")
                    V3 = app.d3Spinner.Value;
                    th3 = DHTab(3,1); D3 = DHTab(3,2)+V3; L3 = DHTab(3,3); al3 = DHTab(3,4);
                end
                if (value4=="Prismatic")
                    V4 = app.d4Spinner.Value;
                    th4 = DHTab(4,1); D4 = DHTab(4,2)+V4; L4 = DHTab(4,3); al4 = DHTab(4,4);
                end
                if (value1=="Revolute")
                    th1 = DHTab(1,1); D1 = DHTab(1,2); L1 = DHTab(1,3); al1 = DHTab(1,4);
                end
                if (value2=="Revolute")
                    th2 = DHTab(2,1); D2 = DHTab(2,2); L2 = DHTab(2,3); al2 = DHTab(2,4);
                end
                if (value3=="Revolute")
                    th3 = DHTab(3,1); D3 = DHTab(3,2); L3 = DHTab(3,3); al3 = DHTab(3,4);
                end
                if (value4=="Revolute")
                    th4 = DHTab(4,1); D4 = DHTab(4,2); L4 = DHTab(4,3); al4 = DHTab(4,4);
                end

                L5=0; D5=0; th5 = 0; al5 = 0;
                L6=0; D6=0; th6 = 0; al6 = 0;
                L7=0; D7=0; th7 = 0; al7 = 0;
                L8=0; D8=0; th8 = 0; al8 = 0;
                L9=0; D9=0; th9 = 0; al9 = 0;
                L10=0; D10=0; th10 = 0; al10= 0;
            elseif DOF=='5'
                if (value1=="Prismatic")
                    V1 = app.d1Spinner.Value;
                    th1 = DHTab(1,1); D1 = DHTab(1,2)+V1; L1 = DHTab(1,3); al1 = DHTab(1,4);
                end
                if (value2=="Prismatic")
                    V2 = app.d2Spinner.Value;
                    th2 = DHTab(2,1); D2 = DHTab(2,2)+V2; L2 = DHTab(2,3); al2 = DHTab(2,4);
                end
                if (value3=="Prismatic")
                    V3 = app.d3Spinner.Value;
                    th3 = DHTab(3,1); D3 = DHTab(3,2)+V3; L3 = DHTab(3,3); al3 = DHTab(3,4);
                end
                if (value4=="Prismatic")
                    V4 = app.d4Spinner.Value;
                    th4 = DHTab(4,1); D4 = DHTab(4,2)+V4; L4 = DHTab(4,3); al4 = DHTab(4,4);
                end
                if (value5=="Prismatic")
                    V5 = app.d5Spinner.Value;
                    th5 = DHTab(5,1); D5 = DHTab(5,2)+V5; L5 = DHTab(5,3); al5 = DHTab(5,4);
                end
                if (value1=="Revolute")
                    th1 = DHTab(1,1); D1 = DHTab(1,2); L1 = DHTab(1,3); al1 = DHTab(1,4);
                end
                if (value2=="Revolute")
                    th2 = DHTab(2,1); D2 = DHTab(2,2); L2 = DHTab(2,3); al2 = DHTab(2,4);
                end
                if (value3=="Revolute")
                    th3 = DHTab(3,1); D3 = DHTab(3,2); L3 = DHTab(3,3); al3 = DHTab(3,4);
                end
                if (value4=="Revolute")
                    th4 = DHTab(4,1); D4 = DHTab(4,2); L4 = DHTab(4,3); al4 = DHTab(4,4);
                end
                if (value5=="Revolute")
                    th5 = DHTab(5,1); D5 = DHTab(5,2); L5 = DHTab(5,3); al5 = DHTab(5,4);
                end
                L6=0; D6=0; th6 = 0; al6 = 0;
                L7=0; D7=0; th7 = 0; al7 = 0;
                L8=0; D8=0; th8 = 0; al8 = 0;
                L9=0; D9=0; th9 = 0; al9 = 0;
                L10=0; D10=0; th10 = 0; al10= 0;
            elseif DOF=='6'
                if (value1=="Prismatic")
                    V1 = app.d1Spinner.Value;
                    th1 = DHTab(1,1); D1 = DHTab(1,2)+V1; L1 = DHTab(1,3); al1 = DHTab(1,4);
                end
                if (value2=="Prismatic")
                    V2 = app.d2Spinner.Value;
                    th2 = DHTab(2,1); D2 = DHTab(2,2)+V2; L2 = DHTab(2,3); al2 = DHTab(2,4);
                end
                if (value3=="Prismatic")
                    V3 = app.d3Spinner.Value;
                    th3 = DHTab(3,1); D3 = DHTab(3,2)+V3; L3 = DHTab(3,3); al3 = DHTab(3,4);
                end
                if (value4=="Prismatic")
                    V4 = app.d4Spinner.Value;
                    th4 = DHTab(4,1); D4 = DHTab(4,2)+V4; L4 = DHTab(4,3); al4 = DHTab(4,4);
                end
                if (value5=="Prismatic")
                    V5 = app.d5Spinner.Value;
                    th5 = DHTab(5,1); D5 = DHTab(5,2)+V5; L5 = DHTab(5,3); al5 = DHTab(5,4);
                end
                if (value6=="Prismatic")
                    V6 = app.d6Spinner.Value;
                    th6 = DHTab(6,1); D6 = DHTab(6,2)+V6; L6 = DHTab(6,3); al6 = DHTab(6,4);
                end
                if (value1=="Revolute")
                    th1 = DHTab(1,1); D1 = DHTab(1,2); L1 = DHTab(1,3); al1 = DHTab(1,4);
                end
                if (value2=="Revolute")
                    th2 = DHTab(2,1); D2 = DHTab(2,2); L2 = DHTab(2,3); al2 = DHTab(2,4);
                end
                if (value3=="Revolute")
                    th3 = DHTab(3,1); D3 = DHTab(3,2); L3 = DHTab(3,3); al3 = DHTab(3,4);
                end
                if (value4=="Revolute")
                    th4 = DHTab(4,1); D4 = DHTab(4,2); L4 = DHTab(4,3); al4 = DHTab(4,4);
                end
                if (value5=="Revolute")
                    th5 = DHTab(5,1); D5 = DHTab(5,2); L5 = DHTab(5,3); al5 = DHTab(5,4);
                end
                if (value6=="Revolute")
                    th6 = DHTab(6,1); D6 = DHTab(6,2); L6 = DHTab(6,3); al6 = DHTab(6,4);
                end
                L7=0; D7=0; th7 = 0; al7 = 0;
                L8=0; D8=0; th8 = 0; al8 = 0;
                L9=0; D9=0; th9 = 0; al9 = 0;
                L10=0; D10=0; th10 = 0; al10= 0;
            elseif DOF=='7'
                if (value1=="Prismatic")
                    V1 = app.d1Spinner.Value;
                    th1 = DHTab(1,1); D1 = DHTab(1,2)+V1; L1 = DHTab(1,3); al1 = DHTab(1,4);
                end
                if (value2=="Prismatic")
                    V2 = app.d2Spinner.Value;
                    th2 = DHTab(2,1); D2 = DHTab(2,2)+V2; L2 = DHTab(2,3); al2 = DHTab(2,4);
                end
                if (value3=="Prismatic")
                    V3 = app.d3Spinner.Value;
                    th3 = DHTab(3,1); D3 = DHTab(3,2)+V3; L3 = DHTab(3,3); al3 = DHTab(3,4);
                end
                if (value4=="Prismatic")
                    V4 = app.d4Spinner.Value;
                    th4 = DHTab(4,1); D4 = DHTab(4,2)+V4; L4 = DHTab(4,3); al4 = DHTab(4,4);
                end
                if (value5=="Prismatic")
                    V5 = app.d5Spinner.Value;
                    th5 = DHTab(5,1); D5 = DHTab(5,2)+V5; L5 = DHTab(5,3); al5 = DHTab(5,4);
                end
                if (value6=="Prismatic")
                    V6 = app.d6Spinner.Value;
                    th6 = DHTab(6,1); D6 = DHTab(6,2)+V6; L6 = DHTab(6,3); al6 = DHTab(6,4);
                end
                if (value7=="Prismatic")
                    V7 = app.d7Spinner.Value;
                    th7 = DHTab(7,1); D7 = DHTab(7,2)+V7; L7 = DHTab(7,3); al7 = DHTab(7,4);
                end
                if (value1=="Revolute")
                    th1 = DHTab(1,1); D1 = DHTab(1,2); L1 = DHTab(1,3); al1 = DHTab(1,4);
                end
                if (value2=="Revolute")
                    th2 = DHTab(2,1); D2 = DHTab(2,2); L2 = DHTab(2,3); al2 = DHTab(2,4);
                end
                if (value3=="Revolute")
                    th3 = DHTab(3,1); D3 = DHTab(3,2); L3 = DHTab(3,3); al3 = DHTab(3,4);
                end
                if (value4=="Revolute")
                    th4 = DHTab(4,1); D4 = DHTab(4,2); L4 = DHTab(4,3); al4 = DHTab(4,4);
                end
                if (value5=="Revolute")
                    th5 = DHTab(5,1); D5 = DHTab(5,2); L5 = DHTab(5,3); al5 = DHTab(5,4);
                end
                if (value6=="Revolute")
                    th6 = DHTab(6,1); D6 = DHTab(6,2); L6 = DHTab(6,3); al6 = DHTab(6,4);
                end
                if (value7=="Revolute")
                    th7 = DHTab(7,1); D7 = DHTab(7,2); L7 = DHTab(7,3); al7 = DHTab(7,4);
                end
                L8=0; D8=0; th8 = 0; al8 = 0;
                L9=0; D9=0; th9 = 0; al9 = 0;
                L10=0; D10=0; th10 = 0; al10= 0;
            elseif DOF=='8'
                if (value1=="Prismatic")
                    V1 = app.d1Spinner.Value;
                    th1 = DHTab(1,1); D1 = DHTab(1,2)+V1; L1 = DHTab(1,3); al1 = DHTab(1,4);
                end
                if (value2=="Prismatic")
                    V2 = app.d2Spinner.Value;
                    th2 = DHTab(2,1); D2 = DHTab(2,2)+V2; L2 = DHTab(2,3); al2 = DHTab(2,4);
                end
                if (value3=="Prismatic")
                    V3 = app.d3Spinner.Value;
                    th3 = DHTab(3,1); D3 = DHTab(3,2)+V3; L3 = DHTab(3,3); al3 = DHTab(3,4);
                end
                if (value4=="Prismatic")
                    V4 = app.d4Spinner.Value;
                    th4 = DHTab(4,1); D4 = DHTab(4,2)+V4; L4 = DHTab(4,3); al4 = DHTab(4,4);
                end
                if (value5=="Prismatic")
                    V5 = app.d5Spinner.Value;
                    th5 = DHTab(5,1); D5 = DHTab(5,2)+V5; L5 = DHTab(5,3); al5 = DHTab(5,4);
                end
                if (value6=="Prismatic")
                    V6 = app.d6Spinner.Value;
                    th6 = DHTab(6,1); D6 = DHTab(6,2)+V6; L6 = DHTab(6,3); al6 = DHTab(6,4);
                end
                if (value7=="Prismatic")
                    V7 = app.d7Spinner.Value;
                    th7 = DHTab(7,1); D7 = DHTab(7,2)+V7; L7 = DHTab(7,3); al7 = DHTab(7,4);
                end
                if (value8=="Prismatic")
                    V8 = app.d8Spinner.Value;
                    th8 = DHTab(8,1); D8 = DHTab(8,2)+V8; L8 = DHTab(8,3); al8 = DHTab(8,4);
                end
                if (value1=="Revolute")
                    th1 = DHTab(1,1); D1 = DHTab(1,2); L1 = DHTab(1,3); al1 = DHTab(1,4);
                end
                if (value2=="Revolute")
                    th2 = DHTab(2,1); D2 = DHTab(2,2); L2 = DHTab(2,3); al2 = DHTab(2,4);
                end
                if (value3=="Revolute")
                    th3 = DHTab(3,1); D3 = DHTab(3,2); L3 = DHTab(3,3); al3 = DHTab(3,4);
                end
                if (value4=="Revolute")
                    th4 = DHTab(4,1); D4 = DHTab(4,2); L4 = DHTab(4,3); al4 = DHTab(4,4);
                end
                if (value5=="Revolute")
                    th5 = DHTab(5,1); D5 = DHTab(5,2); L5 = DHTab(5,3); al5 = DHTab(5,4);
                end
                if (value6=="Revolute")
                    th6 = DHTab(6,1); D6 = DHTab(6,2); L6 = DHTab(6,3); al6 = DHTab(6,4);
                end
                if (value7=="Revolute")
                    th7 = DHTab(7,1); D7 = DHTab(7,2); L7 = DHTab(7,3); al7 = DHTab(7,4);
                end
                if (value8=="Revolute")
                    th8 = DHTab(8,1); D8 = DHTab(8,2); L8 = DHTab(8,3); al8 = DHTab(8,4);
                end

                L9=0; D9=0; th9 = 0; al9 = 0;
                L10=0; D10=0; th10 = 0; al10= 0;
            elseif DOF=='9'
                if (value1=="Prismatic")
                    V1 = app.d1Spinner.Value;
                    th1 = DHTab(1,1); D1 = DHTab(1,2)+V1; L1 = DHTab(1,3); al1 = DHTab(1,4);
                end
                if (value2=="Prismatic")
                    V2 = app.d2Spinner.Value;
                    th2 = DHTab(2,1); D2 = DHTab(2,2)+V2; L2 = DHTab(2,3); al2 = DHTab(2,4);
                end
                if (value3=="Prismatic")
                    V3 = app.d3Spinner.Value;
                    th3 = DHTab(3,1); D3 = DHTab(3,2)+V3; L3 = DHTab(3,3); al3 = DHTab(3,4);
                end
                if (value4=="Prismatic")
                    V4 = app.d4Spinner.Value;
                    th4 = DHTab(4,1); D4 = DHTab(4,2)+V4; L4 = DHTab(4,3); al4 = DHTab(4,4);
                end
                if (value5=="Prismatic")
                    V5 = app.d5Spinner.Value;
                    th5 = DHTab(5,1); D5 = DHTab(5,2)+V5; L5 = DHTab(5,3); al5 = DHTab(5,4);
                end
                if (value6=="Prismatic")
                    V6 = app.d6Spinner.Value;
                    th6 = DHTab(6,1); D6 = DHTab(6,2)+V6; L6 = DHTab(6,3); al6 = DHTab(6,4);
                end
                if (value7=="Prismatic")
                    V7 = app.d7Spinner.Value;
                    th7 = DHTab(7,1); D7 = DHTab(7,2)+V7; L7 = DHTab(7,3); al7 = DHTab(7,4);
                end
                if (value8=="Prismatic")
                    V8 = app.d8Spinner.Value;
                    th8 = DHTab(8,1); D8 = DHTab(8,2)+V8; L8 = DHTab(8,3); al8 = DHTab(8,4);
                end
                if (value9=="Prismatic")
                    V9 = app.d9Spinner.Value;
                    th9 = DHTab(9,1); D9 = DHTab(9,2)+V9; L9 = DHTab(9,3); al9 = DHTab(9,4);
                end
                if (value1=="Revolute")
                    th1 = DHTab(1,1); D1 = DHTab(1,2); L1 = DHTab(1,3); al1 = DHTab(1,4);
                end
                if (value2=="Revolute")
                    th2 = DHTab(2,1); D2 = DHTab(2,2); L2 = DHTab(2,3); al2 = DHTab(2,4);
                end
                if (value3=="Revolute")
                    th3 = DHTab(3,1); D3 = DHTab(3,2); L3 = DHTab(3,3); al3 = DHTab(3,4);
                end
                if (value4=="Revolute")
                    th4 = DHTab(4,1); D4 = DHTab(4,2); L4 = DHTab(4,3); al4 = DHTab(4,4);
                end
                if (value5=="Revolute")
                    th5 = DHTab(5,1); D5 = DHTab(5,2); L5 = DHTab(5,3); al5 = DHTab(5,4);
                end
                if (value6=="Revolute")
                    th6 = DHTab(6,1); D6 = DHTab(6,2); L6 = DHTab(6,3); al6 = DHTab(6,4);
                end
                if (value7=="Revolute")
                    th7 = DHTab(7,1); D7 = DHTab(7,2); L7 = DHTab(7,3); al7 = DHTab(7,4);
                end
                if (value8=="Revolute")
                    th8 = DHTab(8,1); D8 = DHTab(8,2); L8 = DHTab(8,3); al8 = DHTab(8,4);
                end
                if (value9=="Revolute")
                    th9 = DHTab(9,1); D9 = DHTab(9,2); L9 = DHTab(9,3); al9 = DHTab(9,4);
                end

                L10=0; D10=0; th10 = 0; al10= 0;
            elseif DOF=='10'
                if (value1=="Prismatic")
                    V1 = app.d1Spinner.Value;
                    th1 = DHTab(1,1); D1 = DHTab(1,2)+V1; L1 = DHTab(1,3); al1 = DHTab(1,4);
                end
                if (value2=="Prismatic")
                    V2 = app.d2Spinner.Value;
                    th2 = DHTab(2,1); D2 = DHTab(2,2)+V2; L2 = DHTab(2,3); al2 = DHTab(2,4);
                end
                if (value3=="Prismatic")
                    V3 = app.d3Spinner.Value;
                    th3 = DHTab(3,1); D3 = DHTab(3,2)+V3; L3 = DHTab(3,3); al3 = DHTab(3,4);
                end
                if (value4=="Prismatic")
                    V4 = app.d4Spinner.Value;
                    th4 = DHTab(4,1); D4 = DHTab(4,2)+V4; L4 = DHTab(4,3); al4 = DHTab(4,4);
                end
                if (value5=="Prismatic")
                    V5 = app.d5Spinner.Value;
                    th5 = DHTab(5,1); D5 = DHTab(5,2)+V5; L5 = DHTab(5,3); al5 = DHTab(5,4);
                end
                if (value6=="Prismatic")
                    V6 = app.d6Spinner.Value;
                    th6 = DHTab(6,1); D6 = DHTab(6,2)+V6; L6 = DHTab(6,3); al6 = DHTab(6,4);
                end
                if (value7=="Prismatic")
                    V7 = app.d7Spinner.Value;
                    th7 = DHTab(7,1); D7 = DHTab(7,2)+V7; L7 = DHTab(7,3); al7 = DHTab(7,4);
                end
                if (value8=="Prismatic")
                    V8 = app.d8Spinner.Value;
                    th8 = DHTab(8,1); D8 = DHTab(8,2)+V8; L8 = DHTab(8,3); al8 = DHTab(8,4);
                end
                if (value9=="Prismatic")
                    V9 = app.d9Spinner.Value;
                    th9 = DHTab(9,1); D9 = DHTab(9,2)+V9; L9 = DHTab(9,3); al9 = DHTab(9,4);
                end
                if (value10=="Prismatic")
                    V10 = app.d10Spinner.Value;
                    th10 = DHTab(10,1); D10 = DHTab(10,2)+V10; L10 = DHTab(10,3); al10 = DHTab(10,4);
                end
                if (value1=="Revolute")
                    th1 = DHTab(1,1); D1 = DHTab(1,2); L1 = DHTab(1,3); al1 = DHTab(1,4);
                end
                if (value2=="Revolute")
                    th2 = DHTab(2,1); D2 = DHTab(2,2); L2 = DHTab(2,3); al2 = DHTab(2,4);
                end
                if (value3=="Revolute")
                    th3 = DHTab(3,1); D3 = DHTab(3,2); L3 = DHTab(3,3); al3 = DHTab(3,4);
                end
                if (value4=="Revolute")
                    th4 = DHTab(4,1); D4 = DHTab(4,2); L4 = DHTab(4,3); al4 = DHTab(4,4);
                end
                if (value5=="Revolute")
                    th5 = DHTab(5,1); D5 = DHTab(5,2); L5 = DHTab(5,3); al5 = DHTab(5,4);
                end
                if (value6=="Revolute")
                    th6 = DHTab(6,1); D6 = DHTab(6,2); L6 = DHTab(6,3); al6 = DHTab(6,4);
                end
                if (value7=="Revolute")
                    th7 = DHTab(7,1); D7 = DHTab(7,2); L7 = DHTab(7,3); al7 = DHTab(7,4);
                end
                if (value8=="Revolute")
                    th8 = DHTab(8,1); D8 = DHTab(8,2); L8 = DHTab(8,3); al8 = DHTab(8,4);
                end
                if (value9=="Revolute")
                    th9 = DHTab(9,1); D9 = DHTab(9,2); L9 = DHTab(9,3); al9 = DHTab(9,4);
                end
                if (value10=="Revolute")
                    th10 = DHTab(10,1); D10 = DHTab(10,2); L10 = DHTab(10,3); al10 = DHTab(10,4);
                end

            end

            a1 = L1; alpha1 = al1; d1 = D1; theta1 = th1;  % link-1
            a2 = L2; alpha2 = al2; d2 = D2; theta2 = th2;  % link-2
            a3 = L3; alpha3 = al3; d3 = D3; theta3 = th3;  % link-3
            a4 = L4; alpha4 = al4; d4 = D4; theta4 = th4;  % link-4
            a5 = L5; alpha5 = al5; d5 = D5; theta5 = th5;  % link-5
            a6 = L6; alpha6 = al6; d6 = D6; theta6 = th6;  % link-6
            a7 = L7; alpha7 = al7; d7 = D7; theta7 = th7;  % link-7
            a8 = L8; alpha8 = al8; d8 = D8; theta8 = th8;  % link-8
            a9 = L9; alpha9 = al9; d9 = D9; theta9 = th9;  % link-9
            a10 = L10; alpha10 = al10; d10 = D10; theta10 = th10;  % link-10

            H0_1 = DH(theta1, d1, a1, alpha1);
            H1_2 = DH(theta2, d2, a2, alpha2);
            H2_3 = DH(theta3, d3, a3, alpha3);
            H3_4 = DH(theta4, d4, a4, alpha4);
            H4_5 = DH(theta5, d5, a5, alpha5);
            H5_6 = DH(theta6, d6, a6, alpha6);
            H6_7 = DH(theta7, d7, a7, alpha7);
            H7_8 = DH(theta8, d8, a8, alpha8);
            H8_9 = DH(theta9, d9, a9, alpha9);
            H9_10 = DH(theta10, d10, a10, alpha10);


            H0_2 = H0_1 * H1_2;
            H0_3 = H0_2 * H2_3;
            H0_4 = H0_3 * H3_4;
            H0_5 = H0_4 * H4_5;
            H0_6 = H0_5 * H5_6;
            H0_7 = H0_6 * H6_7;
            H0_8 = H0_7 * H7_8;
            H0_9 = H0_8 * H8_9;
            H0_10 = H0_9 * H9_10;

            x1 = H0_1(1,4); x2 = H0_2(1,4); x3 = H0_3(1,4); x4 = H0_4(1,4); x5 = H0_5(1,4);
            y1 = H0_1(2,4); y2 = H0_2(2,4); y3 = H0_3(2,4); y4 = H0_4(2,4); y5 = H0_5(2,4);
            z1 = H0_1(3,4); z2 = H0_2(3,4); z3 = H0_3(3,4); z4 = H0_4(3,4); z5 = H0_5(3,4);

            x6 = H0_6(1,4); x7 = H0_7(1,4); x8 = H0_8(1,4); x9 = H0_9(1,4); x10 = H0_10(1,4);
            y6 = H0_6(2,4); y7 = H0_7(2,4); y8 = H0_8(2,4); y9 = H0_9(2,4); y10 = H0_10(2,4);
            z6 = H0_6(3,4); z7 = H0_7(3,4); z8 = H0_8(3,4); z9 = H0_9(3,4); z10 = H0_10(3,4);

            % Link - 1
            plot3(app.UIAxes,[0 x1],[0 y1],[0 z1],'o-','linewidth',2)
            hold(app.UIAxes,'on')

            % Link - 2
            plot3(app.UIAxes,[x1 x2],[y1 y2],[z1 z2],'o-','linewidth',2)
            hold(app.UIAxes,'on')

            % Link - 3
            plot3(app.UIAxes,[x2 x3],[y2 y3],[z2 z3],'o-','linewidth',2)
            hold(app.UIAxes,'on')

            % Link - 4
            plot3(app.UIAxes,[x3 x4],[y3 y4],[z3 z4],'o-','linewidth',2)
            hold(app.UIAxes,'on')

            % Link - 5
            plot3(app.UIAxes,[x4 x5],[y4 y5],[z4 z5],'o-','linewidth',2)
            hold(app.UIAxes,'on')


            % Link - 6
            plot3(app.UIAxes,[x5 x6],[y5 y6],[z5 z6],'o-','linewidth',2)
            hold(app.UIAxes,'on')

            % Link - 7
            plot3(app.UIAxes,[x6 x7],[y6 y7],[z6 z7],'o-','linewidth',2)
            hold(app.UIAxes,'on')

            % Link - 8
            plot3(app.UIAxes,[x7 x8],[y7 y8],[z7 z8],'o-','linewidth',2)
            hold(app.UIAxes,'on')

            % Link - 9
            plot3(app.UIAxes,[x8 x9],[y8 y9],[z8 z9],'o-','linewidth',2)
            hold(app.UIAxes,'on')

            % Link - 10
            plot3(app.UIAxes,[x9 x10],[y9 y10],[z9 z10],'o-','linewidth',2)
            hold(app.UIAxes,'off')
            ax.XDir = 'normal';
            ax.YDir = 'normal';
            ax.ZDir = 'normal';
            xlabel(app.UIAxes,"X-axis")
            ylabel(app.UIAxes,"Y-axis")
            zlabel(app.UIAxes,"Z-axis")
            app.UIAxes.GridLineStyle = '-.';
            app.UIAxes.GridColor = [1 0 0]

            grid(app.UIAxes,"on")
            if(value1=="Prismatic")
                axis(app.UIAxes,[-(d1+d2+d3+d4+d5+d6+d7+d8+d9+d10+2) (d1+d2+d3+d4+d5+d6+d7+d8+d9+d10+2) -(d1+d2+d3+d4+d5+d6+d7+d8+d9+d10+2) (d1+d2+d3+d4+d5+d6+d7+d8+d9+d10+2) -(d1+d2+d3+d4+d5+d6+d7+d8+d9+d10+1) (d1+d2+d3+d4+d5+d6+d7+d8+d9+d10+1)])
            elseif(value1=="Revolute")
                axis(app.UIAxes,[-(L1+L2+L3+L4+L5+L6+L7+L8+L9+L10+3) (L1+L2+L3+L4+L5+L6+L7+L8+L9+L10+3) -(L1+L2+L3+L4+L5+L6+L7+L8+L9+L10+3) (L1+L2+L3+L4+L5+L6+L7+L8+L9+L10+3) -(L1+L2+L3+L4+L5+L6+L7+L8+L9+L10+d1+d2+d3+d4+d5+d6+d7+d8+d9+d10+1) (L1+L2+L3+L4+L5+L6+L7+L8+L9+L10+d1+d2+d3+d4+d5+d6+d7+d8+d9+d10+1)])
            end

%             B1 = [x1+x2+x3+x4+x5 y1+y2+y3+y4+y5 z1+z2+z3+z4+z5]; B2 = [x6+x7+x8+x9+x10 y6+y7+y8+y9+y10 z6+z7+z8+z9+z10]
%             axlims = max([norm(B1) norm(B2)]);
%             set(app.UIAxes,'xlim',[-1 1]*axlims,'ylim',[-1 1]*axlims,'zlim',[-1 1]*axlims);
%             % plotting 0-lines
%             hold(app.UIAxes,"on");
%             h1 = plot3(app.UIAxes,get(gca,'xlim'),[0 0],[0 0],'r--');
%             h2 = plot3(app.UIAxes,[0 0],get(gca,'ylim'),[0 0],'b--');
%             h3 = plot3(app.UIAxes,[0 0],[0 0],get(gca,'zlim'),'y--');
%             set(app.UIAxes,[h1,h2,h3],'color',[1 1 1]*.3);

            axis(app.UIAxes,'square')

            dof = app.EnterDOFDropDown.Value;
            V1 = app.d1Spinner.Value;
            V2 = app.d2Spinner.Value;
            V3 = app.d3Spinner.Value;
            V4 = app.d4Spinner.Value;
            V5 = app.d5Spinner.Value;
            V6 = app.d6Spinner.Value;
            V7 = app.d7Spinner.Value;
            V8 = app.d8Spinner.Value;
            V9 = app.d9Spinner.Value;
            V10 = app.d10Spinner.Value;
            if dof=='2'
                title(app.UIAxes,'Two-Link Robot');
                app.XEditField.Value = H0_2(1,4);
                app.YEditField.Value = H0_2(2,4);
                app.ZEditField.Value = H0_2(3,4);
                orientation_of_end_effector = H0_2(1:3,1:3);
                app.UITable2.Data = orientation_of_end_effector;
                DHTable = [th1 d1-V1 a1 al1;th2 d2-V2 a2 al2];
                app.UITable.Data = DHTable;
                app.UITable.ColumnName = {'Theta','d','a','Alpha'};
                app.UITable.RowName = {'Joint-1','Joint-2'};
            elseif dof=='3'
                title(app.UIAxes,'Three-Link Robot');
                app.XEditField.Value = H0_3(1,4);
                app.YEditField.Value = H0_3(2,4);
                app.ZEditField.Value = H0_3(3,4);
                orientation_of_end_effector = H0_3(1:3,1:3);
                app.UITable2.Data = orientation_of_end_effector;
                DHTable = [th1 d1-V1 a1 al1;th2 d2-V2 a2 al2;th3 d3-V3 a3 al3;];
                app.UITable.Data = DHTable;
                app.UITable.ColumnName = {'Theta','d','a','Alpha'};
                app.UITable.RowName = {'Joint-1','Joint-2','Joint-3'};
            elseif dof=='4'
                title(app.UIAxes,'Four-Link Robot');
                app.XEditField.Value = H0_4(1,4);
                app.YEditField.Value = H0_4(2,4);
                app.ZEditField.Value = H0_4(3,4);
                orientation_of_end_effector = H0_4(1:3,1:3);
                app.UITable2.Data = orientation_of_end_effector;
                DHTable = [th1 d1-V1 a1 al1;th2 d2-V2 a2 al2;th3 d3-V3 a3 al3;th4 d4-V4 a4 al4];
                app.UITable.Data = DHTable;
                app.UITable.ColumnName = {'Theta','d','a','Alpha'};
                app.UITable.RowName = {'Joint-1','Joint-2','Joint-3','Joint-4'};
            elseif dof=='5'
                title(app.UIAxes,'Five-Link Robot');
                app.XEditField.Value = H0_5(1,4);
                app.YEditField.Value = H0_5(2,4);
                app.ZEditField.Value = H0_5(3,4);
                orientation_of_end_effector = H0_5(1:3,1:3);
                app.UITable2.Data = orientation_of_end_effector;
                DHTable = [th1 d1-V1 a1 al1;th2 d2-V2 a2 al2;th3 d3-V3 a3 al3;th4 d4-V4 a4 al4;th5 d5-V5 a5 al5];
                app.UITable.Data = DHTable;
                app.UITable.ColumnName = {'Theta','d','a','Alpha'};
                app.UITable.RowName = {'Joint-1','Joint-2','Joint-3','Joint-4','Joint-5'};
            elseif dof=='6'
                title(app.UIAxes,'Six-Link Robot');
                app.XEditField.Value = H0_6(1,4);
                app.YEditField.Value = H0_6(2,4);
                app.ZEditField.Value = H0_6(3,4);
                orientation_of_end_effector = H0_6(1:3,1:3);
                app.UITable2.Data = orientation_of_end_effector;
                DHTable = [th1 d1-V1 a1 al1;th2 d2-V2 a2 al2;th3 d3-V3 a3 al3;th4 d4-V4 a4 al4;th5 d5-V5 a5 al5;th6 d6-V6 a6 al6];
                app.UITable.Data = DHTable;
                app.UITable.ColumnName = {'Theta','d','a','Alpha'};
                app.UITable.RowName = {'Joint-1','Joint-2','Joint-3','Joint-4','Joint-5','Joint-6'};
            elseif dof=='7'
                title(app.UIAxes,'Seven-Link Robot');
                app.XEditField.Value = H0_7(1,4);
                app.YEditField.Value = H0_7(2,4);
                app.ZEditField.Value = H0_7(3,4);
                orientation_of_end_effector = H0_7(1:3,1:3);
                app.UITable2.Data = orientation_of_end_effector;
                DHTable = [th1 d1-V1 a1 al1;th2 d2-V2 a2 al2;th3 d3-V3 a3 al3;th4 d4-V4 a4 al4;th5 d5-V5 a5 al5;th6 d6-V6 a6 al6;th7 d7-V7 a7 al7];
                app.UITable.Data = DHTable;
                app.UITable.ColumnName = {'Theta','d','a','Alpha'};
                app.UITable.RowName = {'Joint-1','Joint-2','Joint-3','Joint-4','Joint-5','Joint-6','Joint-7'};
            elseif dof=='8'
                title(app.UIAxes,'Eight-Link Robot');
                app.XEditField.Value = H0_8(1,4);
                app.YEditField.Value = H0_8(2,4);
                app.ZEditField.Value = H0_8(3,4);
                orientation_of_end_effector = H0_8(1:3,1:3);
                app.UITable2.Data = orientation_of_end_effector;
                DHTable = [th1 d1-V1 a1 al1;th2 d2-V2 a2 al2;th3 d3-V3 a3 al3;th4 d4-V4 a4 al4;th5 d5-V5 a5 al5;th6 d6-V6 a6 al6;th7 d7-V7 a7 al7;th8 d8-V8 a8 al8];
                app.UITable.Data = DHTable;
                app.UITable.ColumnName = {'Theta','d','a','Alpha'};
                app.UITable.RowName = {'Joint-1','Joint-2','Joint-3','Joint-4','Joint-5','Joint-6','Joint-7','Joint-8'};
            elseif dof=='9'
                title(app.UIAxes,'Nine-Link Robot');
                app.XEditField.Value = H0_9(1,4);
                app.YEditField.Value = H0_9(2,4);
                app.ZEditField.Value = H0_9(3,4);
                orientation_of_end_effector = H0_9(1:3,1:3);
                app.UITable2.Data = orientation_of_end_effector;
                DHTable = [th1 d1-V1 a1 al1;th2 d2-V2 a2 al2;th3 d3-V3 a3 al3;th4 d4-V4 a4 al4;th5 d5-V5 a5 al5;th6 d6-V6 a6 al6;th7 d7-V7 a7 al7;th8 d8-V8 a8 al8;th9 d9-V9 a9 al9];
                app.UITable.Data = DHTable;
                app.UITable.ColumnName = {'Theta','d','a','Alpha'};
                app.UITable.RowName = {'Joint-1','Joint-2','Joint-3','Joint-4','Joint-5','Joint-6','Joint-7','Joint-8','Joint-9'};
            elseif dof=='10'
                title(app.UIAxes,'Ten-Link Robot');
                app.XEditField.Value = H0_10(1,4);
                app.YEditField.Value = H0_10(2,4);
                app.ZEditField.Value = H0_10(3,4);
                orientation_of_end_effector = H0_10(1:3,1:3);
                app.UITable2.Data = orientation_of_end_effector;
                DHTable = [th1 d1-V1 a1 al1;th2 d2-V2 a2 al2;th3 d3-V3 a3 al3;th4 d4-V4 a4 al4;th5 d5-V5 a5 al5;th6 d6-V6 a6 al6;th7 d7-V7 a7 al7;th8 d8-V8 a8 al8;th9 d9-V9 a9 al9;th10 d10-V10 a10 al10];
                app.UITable.Data = DHTable;
                app.UITable.ColumnName = {'Theta','d','a','Alpha'};
                app.UITable.RowName = {'Joint-1','Joint-2','Joint-3','Joint-4','Joint-5','Joint-6','Joint-7','Joint-8','Joint-9','Joint-10'};
            end


            function [A] = DH(theta, d, a, alpha)

                A = [cosd(theta) -sind(theta)*(cosd(alpha)) sind(theta)*(sind(alpha)) a*cosd(theta);
                    sind(theta) cosd(theta)*(cosd(alpha)) -cosd(theta)*(sind(alpha))  a*sind(theta);
                    0               (sind(alpha))            (cosd(alpha))             d       ;
                    0                   0                         0                    1      ];
            end
        end

        % Button pushed function: WorkEnvelopeButton
        function WorkEnvelopeButtonPushed(app, event)
            DHTab = app.UITable.Data;
            global p;
            DOF = app.EnterDOFDropDown.Value;
            if DOF=='2'
                if p~=0
                    z=0;
                    l1 = DHTab(1,3);
                    l2 = DHTab(2,3);

                    %Calculating and ploting work volume
                    wth1=0:5:360;
                    wphi1=wth1;

                    lt=length(wth1);

                    xw3=zeros(1,lt*lt);
                    yw3=zeros(1,lt*lt);
                    zw3=zeros(1,lt*lt);

                    xt2=zeros(1,lt);
                    yt2=zeros(1,lt);
                    zt2=zeros(1,lt);

                    for i=1:lt
                        for j=1:lt
                            x1=l1*sin(wphi1(i)*pi/180).*cos(wth1(j)*pi/180);
                            y1=l1*sin(wphi1(i)*pi/180).*sin(wth1(j)*pi/180);
                            z1=z+l1*cos(wphi1(i)*pi/180);
                            xt2(j)=x1+l2*sin(wphi1(i)*pi/180).*cos(wth1(j)*pi/180);
                            yt2(j)=y1+l2*sin(wphi1(i)*pi/180).*sin(wth1(j)*pi/180);
                            zt2(j)=z1+l2*cos(wphi1(i)*pi/180);
                        end
                        x2(i*lt-(lt-1):lt*i)=xt2;
                        y2(i*lt-(lt-1):lt*i)=yt2;
                        z2(i*lt-(lt-1):lt*i)=zt2;
                    end
                    %                     for i=1:lt
                    %                         for j=1:lt
                    %                             for k = 1:lt
                    %                                 x1=l1*cos(wth1(i)*pi/180);
                    %                                 y1=l1*sin(wth1(i)*pi/180);
                    %                                 z1=z+d1;
                    %                                 xt2(j)=x1+l2*cos((pi*wth1(i))/180)*cos((pi*wth2(j))/180) + d2*sin((pi*alpha1(k))/180)*sin((pi*wth1(i))/180) - l2*cos((pi*alpha1(k))/180)*sin((pi*wth1(i))/180)*sin((pi*wth2(j))/180);
                    %                                 yt2(j)=y1+l2*cos((pi*wth2(j))/180)*sin((pi*wth1(i))/180)- d2*sin((pi*alpha1(k))/180)*cos((pi*wth1(i))/180)+ l2*cos((pi*alpha1(k))/180)*cos((pi*wth1(i))/180)*sin((pi*wth2(j))/180);
                    %                                 zt2(j)=z1+ d2*cos((pi*alpha1(k))/180) + l2*sin((pi*alpha1(k))/180)*sin((pi*wth2(j))/180);
                    %                             end
                    %                             x2(j*lt-(lt-1):lt*j)=xt2;
                    %                             y2(j*lt-(lt-1):lt*j)=yt2;
                    %                             z2(j*lt-(lt-1):lt*j)=zt2;
                    %                         end
                    %                         x2(i*lt-(lt-1):lt*i)=xt2;
                    %                         y2(i*lt-(lt-1):lt*i)=yt2;
                    %                         z2(i*lt-(lt-1):lt*i)=zt2;
                    %                     end
                    %                     hold on

                    plot3(app.UIAxes,x2,y2,z2, 'Color', [.2 0 0]),
                    axis(app.UIAxes,[-(l1+l2+3) (l1+l2+3) -(l1+l2+3) (l1+l2+3) -(l1+l2) (l1+l2+3)]);
                    grid(app.UIAxes,"on");
                    %                 xlabel('x'), ylabel('y'), zlabel('z');
                    hold(app.UIAxes,"off")
                    %end workvolume
                    p=0;
                else
                    p=1;
                end
            elseif DOF=='3'
                if p~=0
                    z=0;
                    l1 = DHTab(1,3);
                    l2 = DHTab(2,3);
                    l3 = DHTab(3,3);

                    %Calculating and ploting work volume
                    wth1=0:5:360;
                    wphi1=wth1;
                    lt=length(wth1);

                    xw3=zeros(1,lt*lt);
                    yw3=zeros(1,lt*lt);
                    zw3=zeros(1,lt*lt);

                    xt2=zeros(1,lt);
                    yt2=zeros(1,lt);
                    zt2=zeros(1,lt);

                    xt3=zeros(1,lt);
                    yt3=zeros(1,lt);
                    zt3=zeros(1,lt);

                    for i=1:lt
                        for j=1:lt
                            x1=l1*sin(wphi1(i)*pi/180).*cos(wth1(j)*pi/180);
                            y1=l1*sin(wphi1(i)*pi/180).*sin(wth1(j)*pi/180);
                            z1=z+l1*cos(wphi1(i)*pi/180);

                            xt2(j)=x1+l2*sin(wphi1(i)*pi/180).*cos(wth1(j)*pi/180);
                            yt2(j)=y1+l2*sin(wphi1(i)*pi/180).*sin(wth1(j)*pi/180);
                            zt2(j)=z1+l2*cos(wphi1(i)*pi/180);

                            xt3(j)=xt2(j)+l3*sin(wphi1(i)*pi/180).*cos(wth1(j)*pi/180);
                            yt3(j)=yt2(j)+l3*sin(wphi1(i)*pi/180).*sin(wth1(j)*pi/180);
                            zt3(j)=zt2(j)+l3*cos(wphi1(i)*pi/180);

                        end
                        x3(i*lt-(lt-1):lt*i)=xt3;
                        y3(i*lt-(lt-1):lt*i)=yt3;
                        z3(i*lt-(lt-1):lt*i)=zt3;
                    end

                    %                     hold on
                    plot3(app.UIAxes,x3,y3,z3, 'Color', [.2 0 0]),
                    axis(app.UIAxes,[-(l1+l2+l3+3) (l1+l2+l3+3) -(l1+l2+l3+3) (l1+l2+l3+3) -(l1+l2+l3) (l1+l2+l3+4)]);
                    grid(app.UIAxes,"on");
                    %                 xlabel('x'), ylabel('y'), zlabel('z');
                    hold(app.UIAxes,"off")
                    %end workvolume
                    p=0;
                else
                    p=1;
                end
            elseif DOF=='4'
                if p~=0
                    z=0;
                    l1 = DHTab(1,3);
                    l2 = DHTab(2,3);
                    l3 = DHTab(3,3);
                    l4 = DHTab(4,3);

                    %Calculating and ploting work volume
                    wth1=0:5:360;
                    wphi1=wth1;
                    lt=length(wth1);

                    xw3=zeros(1,lt*lt);
                    yw3=zeros(1,lt*lt);
                    zw3=zeros(1,lt*lt);

                    xt2=zeros(1,lt);
                    yt2=zeros(1,lt);
                    zt2=zeros(1,lt);

                    xt3=zeros(1,lt);
                    yt3=zeros(1,lt);
                    zt3=zeros(1,lt);

                    xt4=zeros(1,lt);
                    yt4=zeros(1,lt);
                    zt4=zeros(1,lt);

                    for i=1:lt
                        for j=1:lt
                            x1=l1*sin(wphi1(i)*pi/180).*cos(wth1(j)*pi/180);
                            y1=l1*sin(wphi1(i)*pi/180).*sin(wth1(j)*pi/180);
                            z1=z+l1*cos(wphi1(i)*pi/180);

                            xt2(j)=x1+l2*sin(wphi1(i)*pi/180).*cos(wth1(j)*pi/180);
                            yt2(j)=y1+l2*sin(wphi1(i)*pi/180).*sin(wth1(j)*pi/180);
                            zt2(j)=z1+l2*cos(wphi1(i)*pi/180);

                            xt3(j)=xt2(j)+l3*sin(wphi1(i)*pi/180).*cos(wth1(j)*pi/180);
                            yt3(j)=yt2(j)+l3*sin(wphi1(i)*pi/180).*sin(wth1(j)*pi/180);
                            zt3(j)=zt2(j)+l3*cos(wphi1(i)*pi/180);

                            xt4(j)=xt3(j)+l4*sin(wphi1(i)*pi/180).*cos(wth1(j)*pi/180);
                            yt4(j)=yt3(j)+l4*sin(wphi1(i)*pi/180).*sin(wth1(j)*pi/180);
                            zt4(j)=zt3(j)+l4*cos(wphi1(i)*pi/180);

                        end
                        x4(i*lt-(lt-1):lt*i)=xt4;
                        y4(i*lt-(lt-1):lt*i)=yt4;
                        z4(i*lt-(lt-1):lt*i)=zt4;
                    end

                    %                     hold(app.UIAxes,'on')
                    plot3(app.UIAxes,x4,y4,z4, 'Color', [.2 0 0]),
                    axis(app.UIAxes,[-(l1+l2+l3+l4+3) (l1+l2+l3+l4+3) -(l1+l2+l3+l4+3) (l1+l2+l3+l4+3) -(l1+l2+l3+l4) (l1+l2+l3+l4+4)]);
                    grid(app.UIAxes,"on");
                    %                 xlabel('x'), ylabel('y'), zlabel('z');
                    hold(app.UIAxes,"off")
                    %end workvolume
                    p=0;
                else
                    p=1;
                end
            elseif DOF=='5'
                if p~=0
                    z=0;
                    l1 = DHTab(1,3);
                    l2 = DHTab(2,3);
                    l3 = DHTab(3,3);
                    l4 = DHTab(4,3);
                    l5 = DHTab(5,3);

                    %Calculating and ploting work volume
                    wth1=0:5:360;
                    wphi1=wth1;
                    lt=length(wth1);

                    xw3=zeros(1,lt*lt);
                    yw3=zeros(1,lt*lt);
                    zw3=zeros(1,lt*lt);

                    xt2=zeros(1,lt);
                    yt2=zeros(1,lt);
                    zt2=zeros(1,lt);

                    xt3=zeros(1,lt);
                    yt3=zeros(1,lt);
                    zt3=zeros(1,lt);

                    xt4=zeros(1,lt);
                    yt4=zeros(1,lt);
                    zt4=zeros(1,lt);

                    xt5=zeros(1,lt);
                    yt5=zeros(1,lt);
                    zt5=zeros(1,lt);

                    for i=1:lt
                        for j=1:lt
                            x1=l1*sin(wphi1(i)*pi/180).*cos(wth1(j)*pi/180);
                            y1=l1*sin(wphi1(i)*pi/180).*sin(wth1(j)*pi/180);
                            z1=z+l1*cos(wphi1(i)*pi/180);

                            xt2(j)=x1+l2*sin(wphi1(i)*pi/180).*cos(wth1(j)*pi/180);
                            yt2(j)=y1+l2*sin(wphi1(i)*pi/180).*sin(wth1(j)*pi/180);
                            zt2(j)=z1+l2*cos(wphi1(i)*pi/180);

                            xt3(j)=xt2(j)+l3*sin(wphi1(i)*pi/180).*cos(wth1(j)*pi/180);
                            yt3(j)=yt2(j)+l3*sin(wphi1(i)*pi/180).*sin(wth1(j)*pi/180);
                            zt3(j)=zt2(j)+l3*cos(wphi1(i)*pi/180);

                            xt4(j)=xt3(j)+l4*sin(wphi1(i)*pi/180).*cos(wth1(j)*pi/180);
                            yt4(j)=yt3(j)+l4*sin(wphi1(i)*pi/180).*sin(wth1(j)*pi/180);
                            zt4(j)=zt3(j)+l4*cos(wphi1(i)*pi/180);

                            xt5(j)=xt4(j)+l5*sin(wphi1(i)*pi/180).*cos(wth1(j)*pi/180);
                            yt5(j)=yt4(j)+l5*sin(wphi1(i)*pi/180).*sin(wth1(j)*pi/180);
                            zt5(j)=zt4(j)+l5*cos(wphi1(i)*pi/180);

                        end
                        x5(i*lt-(lt-1):lt*i)=xt5;
                        y5(i*lt-(lt-1):lt*i)=yt5;
                        z5(i*lt-(lt-1):lt*i)=zt5;
                    end

                    %                     hold(app.UIAxes,'on')
                    plot3(app.UIAxes,x5,y5,z5, 'Color', [.2 0 0]);
                    axis(app.UIAxes,[-(l1+l2+l3+l4+l5+3) (l1+l2+l3+l4+l5+3) -(l1+l2+l3+l4+l5+3) (l1+l2+l3+l4+l5+3) -(l1+l2+l3+l4+l5) (l1+l2+l3+l4+l5+4)]);
                    grid(app.UIAxes,"on");
                    %                 xlabel('x'), ylabel('y'), zlabel('z');
                    hold(app.UIAxes,"off")
                    %end workvolume
                    p=0;
                else
                    p=1;
                end
            elseif DOF=='6'
                if p~=0
                    z=0;
                    l1 = DHTab(1,3);
                    l2 = DHTab(2,3);
                    l3 = DHTab(3,3);
                    l4 = DHTab(4,3);
                    l5 = DHTab(5,3);
                    l6 = DHTab(6,3);

                    %Calculating and ploting work volume
                    wth1=0:5:360;
                    wphi1=wth1;
                    lt=length(wth1);

                    xw3=zeros(1,lt*lt);
                    yw3=zeros(1,lt*lt);
                    zw3=zeros(1,lt*lt);

                    xt2=zeros(1,lt);
                    yt2=zeros(1,lt);
                    zt2=zeros(1,lt);

                    xt3=zeros(1,lt);
                    yt3=zeros(1,lt);
                    zt3=zeros(1,lt);

                    xt4=zeros(1,lt);
                    yt4=zeros(1,lt);
                    zt4=zeros(1,lt);

                    xt5=zeros(1,lt);
                    yt5=zeros(1,lt);
                    zt5=zeros(1,lt);

                    xt6=zeros(1,lt);
                    yt6=zeros(1,lt);
                    zt6=zeros(1,lt);

                    for i=1:lt
                        for j=1:lt
                            x1=l1*sin(wphi1(i)*pi/180).*cos(wth1(j)*pi/180);
                            y1=l1*sin(wphi1(i)*pi/180).*sin(wth1(j)*pi/180);
                            z1=z+l1*cos(wphi1(i)*pi/180);

                            xt2(j)=x1+l2*sin(wphi1(i)*pi/180).*cos(wth1(j)*pi/180);
                            yt2(j)=y1+l2*sin(wphi1(i)*pi/180).*sin(wth1(j)*pi/180);
                            zt2(j)=z1+l2*cos(wphi1(i)*pi/180);

                            xt3(j)=xt2(j)+l3*sin(wphi1(i)*pi/180).*cos(wth1(j)*pi/180);
                            yt3(j)=yt2(j)+l3*sin(wphi1(i)*pi/180).*sin(wth1(j)*pi/180);
                            zt3(j)=zt2(j)+l3*cos(wphi1(i)*pi/180);

                            xt4(j)=xt3(j)+l4*sin(wphi1(i)*pi/180).*cos(wth1(j)*pi/180);
                            yt4(j)=yt3(j)+l4*sin(wphi1(i)*pi/180).*sin(wth1(j)*pi/180);
                            zt4(j)=zt3(j)+l4*cos(wphi1(i)*pi/180);

                            xt5(j)=xt4(j)+l5*sin(wphi1(i)*pi/180).*cos(wth1(j)*pi/180);
                            yt5(j)=yt4(j)+l5*sin(wphi1(i)*pi/180).*sin(wth1(j)*pi/180);
                            zt5(j)=zt4(j)+l5*cos(wphi1(i)*pi/180);

                            xt6(j)=xt5(j)+l6*sin(wphi1(i)*pi/180).*cos(wth1(j)*pi/180);
                            yt6(j)=yt5(j)+l6*sin(wphi1(i)*pi/180).*sin(wth1(j)*pi/180);
                            zt6(j)=zt5(j)+l6*cos(wphi1(i)*pi/180);

                        end
                        x6(i*lt-(lt-1):lt*i)=xt6;
                        y6(i*lt-(lt-1):lt*i)=yt6;
                        z6(i*lt-(lt-1):lt*i)=zt6;
                    end

                    %                     hold(app.UIAxes,'on')
                    plot3(app.UIAxes,x6,y6,z6, 'Color', [.2 0 0]);
                    axis(app.UIAxes,[-(l1+l2+l3+l4+l5+l6+3) (l1+l2+l3+l4+l5+l6+3) -(l1+l2+l3+l4+l5+l6+3) (l1+l2+l3+l4+l5+l6+3) -(l1+l2+l3+l4+l5+l6) (l1+l2+l3+l4+l5+l6+4)]);
                    grid(app.UIAxes,"on");
                    %                 xlabel('x'), ylabel('y'), zlabel('z');
                    hold(app.UIAxes,"off")
                    %end workvolume
                    p=0;
                else
                    p=1;
                end
            elseif DOF=='7'
                if p~=0
                    z=0;
                    l1 = DHTab(1,3);
                    l2 = DHTab(2,3);
                    l3 = DHTab(3,3);
                    l4 = DHTab(4,3);
                    l5 = DHTab(5,3);
                    l6 = DHTab(6,3);
                    l7 = DHTab(7,3);

                    %Calculating and ploting work volume
                    wth1=0:5:360;
                    wphi1=wth1;
                    lt=length(wth1);

                    xw3=zeros(1,lt*lt);
                    yw3=zeros(1,lt*lt);
                    zw3=zeros(1,lt*lt);

                    xt2=zeros(1,lt);
                    yt2=zeros(1,lt);
                    zt2=zeros(1,lt);

                    xt3=zeros(1,lt);
                    yt3=zeros(1,lt);
                    zt3=zeros(1,lt);

                    xt4=zeros(1,lt);
                    yt4=zeros(1,lt);
                    zt4=zeros(1,lt);

                    xt5=zeros(1,lt);
                    yt5=zeros(1,lt);
                    zt5=zeros(1,lt);

                    xt6=zeros(1,lt);
                    yt6=zeros(1,lt);
                    zt6=zeros(1,lt);

                    xt7=zeros(1,lt);
                    yt7=zeros(1,lt);
                    zt7=zeros(1,lt);

                    for i=1:lt
                        for j=1:lt
                            x1=l1*sin(wphi1(i)*pi/180).*cos(wth1(j)*pi/180);
                            y1=l1*sin(wphi1(i)*pi/180).*sin(wth1(j)*pi/180);
                            z1=z+l1*cos(wphi1(i)*pi/180);

                            xt2(j)=x1+l2*sin(wphi1(i)*pi/180).*cos(wth1(j)*pi/180);
                            yt2(j)=y1+l2*sin(wphi1(i)*pi/180).*sin(wth1(j)*pi/180);
                            zt2(j)=z1+l2*cos(wphi1(i)*pi/180);

                            xt3(j)=xt2(j)+l3*sin(wphi1(i)*pi/180).*cos(wth1(j)*pi/180);
                            yt3(j)=yt2(j)+l3*sin(wphi1(i)*pi/180).*sin(wth1(j)*pi/180);
                            zt3(j)=zt2(j)+l3*cos(wphi1(i)*pi/180);

                            xt4(j)=xt3(j)+l4*sin(wphi1(i)*pi/180).*cos(wth1(j)*pi/180);
                            yt4(j)=yt3(j)+l4*sin(wphi1(i)*pi/180).*sin(wth1(j)*pi/180);
                            zt4(j)=zt3(j)+l4*cos(wphi1(i)*pi/180);

                            xt5(j)=xt4(j)+l5*sin(wphi1(i)*pi/180).*cos(wth1(j)*pi/180);
                            yt5(j)=yt4(j)+l5*sin(wphi1(i)*pi/180).*sin(wth1(j)*pi/180);
                            zt5(j)=zt4(j)+l5*cos(wphi1(i)*pi/180);

                            xt6(j)=xt5(j)+l6*sin(wphi1(i)*pi/180).*cos(wth1(j)*pi/180);
                            yt6(j)=yt5(j)+l6*sin(wphi1(i)*pi/180).*sin(wth1(j)*pi/180);
                            zt6(j)=zt5(j)+l6*cos(wphi1(i)*pi/180);

                            xt7(j)=xt6(j)+l7*sin(wphi1(i)*pi/180).*cos(wth1(j)*pi/180);
                            yt7(j)=yt6(j)+l7*sin(wphi1(i)*pi/180).*sin(wth1(j)*pi/180);
                            zt7(j)=zt6(j)+l7*cos(wphi1(i)*pi/180);

                        end
                        x7(i*lt-(lt-1):lt*i)=xt7;
                        y7(i*lt-(lt-1):lt*i)=yt7;
                        z7(i*lt-(lt-1):lt*i)=zt7;
                    end

                    %                     hold(app.UIAxes,'on')
                    plot3(app.UIAxes,x7,y7,z7, 'Color', [.2 0 0]);
                    axis(app.UIAxes,[-(l1+l2+l3+l4+l5+l6+l7+3) (l1+l2+l3+l4+l5+l6+l7+3) -(l1+l2+l3+l4+l5+l6+l7+3) (l1+l2+l3+l4+l5+l6+l7+3) -(l1+l2+l3+l4+l5+l6+l7) (l1+l2+l3+l4+l5+l6+l7+4)]);
                    grid(app.UIAxes,"on");
                    %                 xlabel('x'), ylabel('y'), zlabel('z');
                    hold(app.UIAxes,"off")
                    %end workvolume
                    p=0;
                else
                    p=1;
                end
            elseif DOF=='8'
                if p~=0
                    z=0;
                    l1 = DHTab(1,3);
                    l2 = DHTab(2,3);
                    l3 = DHTab(3,3);
                    l4 = DHTab(4,3);
                    l5 = DHTab(5,3);
                    l6 = DHTab(6,3);
                    l7 = DHTab(7,3);
                    l8 = DHTab(8,3);

                    %Calculating and ploting work volume
                    wth1=0:5:360;
                    wphi1=wth1;
                    lt=length(wth1);

                    xw3=zeros(1,lt*lt);
                    yw3=zeros(1,lt*lt);
                    zw3=zeros(1,lt*lt);

                    xt2=zeros(1,lt);
                    yt2=zeros(1,lt);
                    zt2=zeros(1,lt);

                    xt3=zeros(1,lt);
                    yt3=zeros(1,lt);
                    zt3=zeros(1,lt);

                    xt4=zeros(1,lt);
                    yt4=zeros(1,lt);
                    zt4=zeros(1,lt);

                    xt5=zeros(1,lt);
                    yt5=zeros(1,lt);
                    zt5=zeros(1,lt);

                    xt6=zeros(1,lt);
                    yt6=zeros(1,lt);
                    zt6=zeros(1,lt);

                    xt7=zeros(1,lt);
                    yt7=zeros(1,lt);
                    zt7=zeros(1,lt);

                    xt8=zeros(1,lt);
                    yt8=zeros(1,lt);
                    zt8=zeros(1,lt);

                    for i=1:lt
                        for j=1:lt
                            x1=l1*sin(wphi1(i)*pi/180).*cos(wth1(j)*pi/180);
                            y1=l1*sin(wphi1(i)*pi/180).*sin(wth1(j)*pi/180);
                            z1=z+l1*cos(wphi1(i)*pi/180);

                            xt2(j)=x1+l2*sin(wphi1(i)*pi/180).*cos(wth1(j)*pi/180);
                            yt2(j)=y1+l2*sin(wphi1(i)*pi/180).*sin(wth1(j)*pi/180);
                            zt2(j)=z1+l2*cos(wphi1(i)*pi/180);

                            xt3(j)=xt2(j)+l3*sin(wphi1(i)*pi/180).*cos(wth1(j)*pi/180);
                            yt3(j)=yt2(j)+l3*sin(wphi1(i)*pi/180).*sin(wth1(j)*pi/180);
                            zt3(j)=zt2(j)+l3*cos(wphi1(i)*pi/180);

                            xt4(j)=xt3(j)+l4*sin(wphi1(i)*pi/180).*cos(wth1(j)*pi/180);
                            yt4(j)=yt3(j)+l4*sin(wphi1(i)*pi/180).*sin(wth1(j)*pi/180);
                            zt4(j)=zt3(j)+l4*cos(wphi1(i)*pi/180);

                            xt5(j)=xt4(j)+l5*sin(wphi1(i)*pi/180).*cos(wth1(j)*pi/180);
                            yt5(j)=yt4(j)+l5*sin(wphi1(i)*pi/180).*sin(wth1(j)*pi/180);
                            zt5(j)=zt4(j)+l5*cos(wphi1(i)*pi/180);

                            xt6(j)=xt5(j)+l6*sin(wphi1(i)*pi/180).*cos(wth1(j)*pi/180);
                            yt6(j)=yt5(j)+l6*sin(wphi1(i)*pi/180).*sin(wth1(j)*pi/180);
                            zt6(j)=zt5(j)+l6*cos(wphi1(i)*pi/180);

                            xt7(j)=xt6(j)+l7*sin(wphi1(i)*pi/180).*cos(wth1(j)*pi/180);
                            yt7(j)=yt6(j)+l7*sin(wphi1(i)*pi/180).*sin(wth1(j)*pi/180);
                            zt7(j)=zt6(j)+l7*cos(wphi1(i)*pi/180);

                            xt8(j)=xt7(j)+l8*sin(wphi1(i)*pi/180).*cos(wth1(j)*pi/180);
                            yt8(j)=yt7(j)+l8*sin(wphi1(i)*pi/180).*sin(wth1(j)*pi/180);
                            zt8(j)=zt7(j)+l8*cos(wphi1(i)*pi/180);

                        end
                        x8(i*lt-(lt-1):lt*i)=xt8;
                        y8(i*lt-(lt-1):lt*i)=yt8;
                        z8(i*lt-(lt-1):lt*i)=zt8;
                    end

                    %                     hold(app.UIAxes,'on')
                    plot3(app.UIAxes,x8,y8,z8, 'Color', [.2 0 0]);
                    axis(app.UIAxes,[-(l1+l2+l3+l4+l5+l6+l7+l8+3) (l1+l2+l3+l4+l5+l6+l7+l8+3) -(l1+l2+l3+l4+l5+l6+l7+l8+3) (l1+l2+l3+l4+l5+l6+l7+l8+3) -(l1+l2+l3+l4+l5+l6+l7+l8) (l1+l2+l3+l4+l5+l6+l7+l8+4)]);
                    grid(app.UIAxes,"on");
                    %                 xlabel('x'), ylabel('y'), zlabel('z');
                    hold(app.UIAxes,"off")
                    %end workvolume
                    p=0;
                else
                    p=1;
                end
            elseif DOF=='9'
                if p~=0
                    z=0;
                    l1 = DHTab(1,3);
                    l2 = DHTab(2,3);
                    l3 = DHTab(3,3);
                    l4 = DHTab(4,3);
                    l5 = DHTab(5,3);
                    l6 = DHTab(6,3);
                    l7 = DHTab(7,3);
                    l8 = DHTab(8,3);
                    l9 = DHTab(9,3);

                    %Calculating and ploting work volume
                    wth1=0:5:360;
                    wphi1=wth1;
                    lt=length(wth1);

                    xw3=zeros(1,lt*lt);
                    yw3=zeros(1,lt*lt);
                    zw3=zeros(1,lt*lt);

                    xt2=zeros(1,lt);
                    yt2=zeros(1,lt);
                    zt2=zeros(1,lt);

                    xt3=zeros(1,lt);
                    yt3=zeros(1,lt);
                    zt3=zeros(1,lt);

                    xt4=zeros(1,lt);
                    yt4=zeros(1,lt);
                    zt4=zeros(1,lt);

                    xt5=zeros(1,lt);
                    yt5=zeros(1,lt);
                    zt5=zeros(1,lt);

                    xt6=zeros(1,lt);
                    yt6=zeros(1,lt);
                    zt6=zeros(1,lt);

                    xt7=zeros(1,lt);
                    yt7=zeros(1,lt);
                    zt7=zeros(1,lt);

                    xt8=zeros(1,lt);
                    yt8=zeros(1,lt);
                    zt8=zeros(1,lt);

                    xt9=zeros(1,lt);
                    yt9=zeros(1,lt);
                    zt9=zeros(1,lt);

                    for i=1:lt
                        for j=1:lt
                            x1=l1*sin(wphi1(i)*pi/180).*cos(wth1(j)*pi/180);
                            y1=l1*sin(wphi1(i)*pi/180).*sin(wth1(j)*pi/180);
                            z1=z+l1*cos(wphi1(i)*pi/180);

                            xt2(j)=x1+l2*sin(wphi1(i)*pi/180).*cos(wth1(j)*pi/180);
                            yt2(j)=y1+l2*sin(wphi1(i)*pi/180).*sin(wth1(j)*pi/180);
                            zt2(j)=z1+l2*cos(wphi1(i)*pi/180);

                            xt3(j)=xt2(j)+l3*sin(wphi1(i)*pi/180).*cos(wth1(j)*pi/180);
                            yt3(j)=yt2(j)+l3*sin(wphi1(i)*pi/180).*sin(wth1(j)*pi/180);
                            zt3(j)=zt2(j)+l3*cos(wphi1(i)*pi/180);

                            xt4(j)=xt3(j)+l4*sin(wphi1(i)*pi/180).*cos(wth1(j)*pi/180);
                            yt4(j)=yt3(j)+l4*sin(wphi1(i)*pi/180).*sin(wth1(j)*pi/180);
                            zt4(j)=zt3(j)+l4*cos(wphi1(i)*pi/180);

                            xt5(j)=xt4(j)+l5*sin(wphi1(i)*pi/180).*cos(wth1(j)*pi/180);
                            yt5(j)=yt4(j)+l5*sin(wphi1(i)*pi/180).*sin(wth1(j)*pi/180);
                            zt5(j)=zt4(j)+l5*cos(wphi1(i)*pi/180);

                            xt6(j)=xt5(j)+l6*sin(wphi1(i)*pi/180).*cos(wth1(j)*pi/180);
                            yt6(j)=yt5(j)+l6*sin(wphi1(i)*pi/180).*sin(wth1(j)*pi/180);
                            zt6(j)=zt5(j)+l6*cos(wphi1(i)*pi/180);

                            xt7(j)=xt6(j)+l7*sin(wphi1(i)*pi/180).*cos(wth1(j)*pi/180);
                            yt7(j)=yt6(j)+l7*sin(wphi1(i)*pi/180).*sin(wth1(j)*pi/180);
                            zt7(j)=zt6(j)+l7*cos(wphi1(i)*pi/180);

                            xt8(j)=xt7(j)+l8*sin(wphi1(i)*pi/180).*cos(wth1(j)*pi/180);
                            yt8(j)=yt7(j)+l8*sin(wphi1(i)*pi/180).*sin(wth1(j)*pi/180);
                            zt8(j)=zt7(j)+l8*cos(wphi1(i)*pi/180);

                            xt9(j)=xt8(j)+l9*sin(wphi1(i)*pi/180).*cos(wth1(j)*pi/180);
                            yt9(j)=yt8(j)+l9*sin(wphi1(i)*pi/180).*sin(wth1(j)*pi/180);
                            zt9(j)=zt8(j)+l9*cos(wphi1(i)*pi/180);

                        end
                        x9(i*lt-(lt-1):lt*i)=xt9;
                        y9(i*lt-(lt-1):lt*i)=yt9;
                        z9(i*lt-(lt-1):lt*i)=zt9;
                    end

                    %                     hold(app.UIAxes,'on')
                    plot3(app.UIAxes,x9,y9,z9, 'Color', [.2 0 0]);
                    axis(app.UIAxes,[-(l1+l2+l3+l4+l5+l6+l7+l8+l9+3) (l1+l2+l3+l4+l5+l6+l7+l8+l9+3) -(l1+l2+l3+l4+l5+l6+l7+l8+l9+3) (l1+l2+l3+l4+l5+l6+l7+l8+l9+3) -(l1+l2+l3+l4+l5+l6+l7+l8+l9) (l1+l2+l3+l4+l5+l6+l7+l8+l9+4)]);
                    grid(app.UIAxes,"on");
                    %                 xlabel('x'), ylabel('y'), zlabel('z');
                    hold(app.UIAxes,"off")
                    %end workvolume
                    p=0;
                else
                    p=1;
                end
            elseif DOF=='10'
                if p~=0
                    z=0;
                    l1 = DHTab(1,3);
                    l2 = DHTab(2,3);
                    l3 = DHTab(3,3);
                    l4 = DHTab(4,3);
                    l5 = DHTab(5,3);
                    l6 = DHTab(6,3);
                    l7 = DHTab(7,3);
                    l8 = DHTab(8,3);
                    l9 = DHTab(9,3);
                    l10 = DHTab(10,3);

                    %Calculating and ploting work volume
                    wth1=0:5:360;
                    wphi1=wth1;
                    lt=length(wth1);

                    xw3=zeros(1,lt*lt);
                    yw3=zeros(1,lt*lt);
                    zw3=zeros(1,lt*lt);

                    xt2=zeros(1,lt);
                    yt2=zeros(1,lt);
                    zt2=zeros(1,lt);

                    xt3=zeros(1,lt);
                    yt3=zeros(1,lt);
                    zt3=zeros(1,lt);

                    xt4=zeros(1,lt);
                    yt4=zeros(1,lt);
                    zt4=zeros(1,lt);

                    xt5=zeros(1,lt);
                    yt5=zeros(1,lt);
                    zt5=zeros(1,lt);

                    xt6=zeros(1,lt);
                    yt6=zeros(1,lt);
                    zt6=zeros(1,lt);

                    xt7=zeros(1,lt);
                    yt7=zeros(1,lt);
                    zt7=zeros(1,lt);

                    xt8=zeros(1,lt);
                    yt8=zeros(1,lt);
                    zt8=zeros(1,lt);

                    xt9=zeros(1,lt);
                    yt9=zeros(1,lt);
                    zt9=zeros(1,lt);

                    xt10=zeros(1,lt);
                    yt10=zeros(1,lt);
                    zt10=zeros(1,lt);

                    for i=1:lt
                        for j=1:lt
                            x1=l1*sin(wphi1(i)*pi/180).*cos(wth1(j)*pi/180);
                            y1=l1*sin(wphi1(i)*pi/180).*sin(wth1(j)*pi/180);
                            z1=z+l1*cos(wphi1(i)*pi/180);

                            xt2(j)=x1+l2*sin(wphi1(i)*pi/180).*cos(wth1(j)*pi/180);
                            yt2(j)=y1+l2*sin(wphi1(i)*pi/180).*sin(wth1(j)*pi/180);
                            zt2(j)=z1+l2*cos(wphi1(i)*pi/180);

                            xt3(j)=xt2(j)+l3*sin(wphi1(i)*pi/180).*cos(wth1(j)*pi/180);
                            yt3(j)=yt2(j)+l3*sin(wphi1(i)*pi/180).*sin(wth1(j)*pi/180);
                            zt3(j)=zt2(j)+l3*cos(wphi1(i)*pi/180);

                            xt4(j)=xt3(j)+l4*sin(wphi1(i)*pi/180).*cos(wth1(j)*pi/180);
                            yt4(j)=yt3(j)+l4*sin(wphi1(i)*pi/180).*sin(wth1(j)*pi/180);
                            zt4(j)=zt3(j)+l4*cos(wphi1(i)*pi/180);

                            xt5(j)=xt4(j)+l5*sin(wphi1(i)*pi/180).*cos(wth1(j)*pi/180);
                            yt5(j)=yt4(j)+l5*sin(wphi1(i)*pi/180).*sin(wth1(j)*pi/180);
                            zt5(j)=zt4(j)+l5*cos(wphi1(i)*pi/180);

                            xt6(j)=xt5(j)+l6*sin(wphi1(i)*pi/180).*cos(wth1(j)*pi/180);
                            yt6(j)=yt5(j)+l6*sin(wphi1(i)*pi/180).*sin(wth1(j)*pi/180);
                            zt6(j)=zt5(j)+l6*cos(wphi1(i)*pi/180);

                            xt7(j)=xt6(j)+l7*sin(wphi1(i)*pi/180).*cos(wth1(j)*pi/180);
                            yt7(j)=yt6(j)+l7*sin(wphi1(i)*pi/180).*sin(wth1(j)*pi/180);
                            zt7(j)=zt6(j)+l7*cos(wphi1(i)*pi/180);

                            xt8(j)=xt7(j)+l8*sin(wphi1(i)*pi/180).*cos(wth1(j)*pi/180);
                            yt8(j)=yt7(j)+l8*sin(wphi1(i)*pi/180).*sin(wth1(j)*pi/180);
                            zt8(j)=zt7(j)+l8*cos(wphi1(i)*pi/180);

                            xt9(j)=xt8(j)+l9*sin(wphi1(i)*pi/180).*cos(wth1(j)*pi/180);
                            yt9(j)=yt8(j)+l9*sin(wphi1(i)*pi/180).*sin(wth1(j)*pi/180);
                            zt9(j)=zt8(j)+l9*cos(wphi1(i)*pi/180);

                            xt10(j)=xt9(j)+l10*sin(wphi1(i)*pi/180).*cos(wth1(j)*pi/180);
                            yt10(j)=yt9(j)+l10*sin(wphi1(i)*pi/180).*sin(wth1(j)*pi/180);
                            zt10(j)=zt9(j)+l10*cos(wphi1(i)*pi/180);

                        end
                        x10(i*lt-(lt-1):lt*i)=xt10;
                        y10(i*lt-(lt-1):lt*i)=yt10;
                        z10(i*lt-(lt-1):lt*i)=zt10;
                    end

                    %                     hold(app.UIAxes,'on')
                    plot3(app.UIAxes,x10,y10,z10, 'Color', [.2 0 0]);
                    axis(app.UIAxes,[-(l1+l2+l3+l4+l5+l6+l7+l8+l9+l10+3) (l1+l2+l3+l4+l5+l6+l7+l8+l9+l10+3) -(l1+l2+l3+l4+l5+l6+l7+l8+l9+l10+3) (l1+l2+l3+l4+l5+l6+l7+l8+l9+l10+3) -(l1+l2+l3+l4+l5+l6+l7+l8+l9+l10) (l1+l2+l3+l4+l5+l6+l7+l8+l9+l10+4)]);
                    grid(app.UIAxes,"on");
                    %                 xlabel('x'), ylabel('y'), zlabel('z');
                    hold(app.UIAxes,"off")
                    %end workvolume
                    p=0;
                else
                    p=1;
                end
            end
        end

        % Value changed function: Joint_1Type
        function Joint_1TypeValueChanged(app, event)
            value = app.Joint_1Type.Value;
            if (value=="Prismatic")
                app.JointVariable.Visible = 'on';
                app.d1Spinner.Visible = 'on';
                app.d1SpinnerLabel.Visible = 'on';
            elseif (value=="Revolute")
                app.d1Spinner.Visible = 'off';
                app.d1SpinnerLabel.Visible = 'off';
                app.d1Spinner.Value = 0;
            end
        end

        % Value changed function: Joint_2Type
        function Joint_2TypeValueChanged(app, event)
            value = app.Joint_2Type.Value;
            if (value=="Prismatic")
                app.JointVariable.Visible = 'on';
                app.d2Spinner.Visible = 'on';
                app.d2SpinnerLabel.Visible = 'on';
            elseif (value=="Revolute")
                app.d2Spinner.Visible = 'off';
                app.d2SpinnerLabel.Visible = 'off';
                app.d2Spinner.Value=0;
            end
        end

        % Value changed function: Joint_3Type
        function Joint_3TypeValueChanged(app, event)
            value = app.Joint_3Type.Value;
            if (value=="Prismatic")
                app.JointVariable.Visible = 'on';
                app.d3Spinner.Visible = 'on';
                app.d3SpinnerLabel.Visible = 'on';
            elseif (value=="Revolute")
                app.d3Spinner.Visible = 'off';
                app.d3SpinnerLabel.Visible = 'off';
                app.d3Spinner.Value=0;
            end
        end

        % Value changed function: Joint_4Type
        function Joint_4TypeValueChanged(app, event)
            value = app.Joint_4Type.Value;
            if (value=="Prismatic")
                app.JointVariable.Visible = 'on';
                app.d4Spinner.Visible = 'on';
                app.d4SpinnerLabel.Visible = 'on';
            elseif (value=="Revolute")
                app.d4Spinner.Visible = 'off';
                app.d4SpinnerLabel.Visible = 'off';
                app.d4Spinner.Value=0;
            end
        end

        % Value changed function: Joint_5Type
        function Joint_5TypeValueChanged(app, event)
            value = app.Joint_5Type.Value;
            if (value=="Prismatic")
                app.JointVariable.Visible = 'on';
                app.d5Spinner.Visible = 'on';
                app.d5SpinnerLabel.Visible = 'on';
            elseif (value=="Revolute")
                app.d5Spinner.Visible = 'off';
                app.d5SpinnerLabel.Visible = 'off';
                app.d5Spinner.Value=0;
            end
        end

        % Value changed function: Joint_6Type
        function Joint_6TypeValueChanged(app, event)
            value = app.Joint_6Type.Value;
            if (value=="Prismatic")
                app.JointVariable.Visible = 'on';
                app.d6Spinner.Visible = 'on';
                app.d6SpinnerLabel.Visible = 'on';
            elseif (value=="Revolute")
                app.d6Spinner.Visible = 'off';
                app.d6SpinnerLabel.Visible = 'off';
                app.d6Spinner.Value=0;
            end
        end

        % Value changed function: Joint_7Type
        function Joint_7TypeValueChanged(app, event)
            value = app.Joint_7Type.Value;
            if (value=="Prismatic")
                app.JointVariable.Visible = 'on';
                app.d7Spinner.Visible = 'on';
                app.d7SpinnerLabel.Visible = 'on';
            elseif (value=="Revolute")
                app.d7Spinner.Visible = 'off';
                app.d7SpinnerLabel.Visible = 'off';
                app.d7Spinner.Value=0;
            end
        end

        % Value changed function: Joint_8Type
        function Joint_8TypeValueChanged(app, event)
            value = app.Joint_8Type.Value;
            if (value=="Prismatic")
                app.JointVariable.Visible = 'on';
                app.d8Spinner.Visible = 'on';
                app.d8SpinnerLabel.Visible = 'on';
            elseif (value=="Revolute")
                app.d8Spinner.Visible = 'off';
                app.d8SpinnerLabel.Visible = 'off';
                app.d8Spinner.Value=0;
            end
        end

        % Value changed function: Joint_9Type
        function Joint_9TypeValueChanged(app, event)
            value = app.Joint_9Type.Value;
            if (value=="Prismatic")
                app.JointVariable.Visible = 'on';
                app.d9Spinner.Visible = 'on';
                app.d9SpinnerLabel.Visible = 'on';
            elseif (value=="Revolute")
                app.d9Spinner.Visible = 'off';
                app.d9SpinnerLabel.Visible = 'off';
                app.d9Spinner.Value=0;
            end
        end

        % Value changed function: Joint_10Type
        function Joint_10TypeValueChanged(app, event)
            value = app.Joint_10Type.Value;
            if (value=="Prismatic")
                app.JointVariable.Visible = 'on';
                app.d10Spinner.Visible = 'on';
                app.d10SpinnerLabel.Visible = 'on';
            elseif (value=="Revolute")
                app.d10Spinner.Visible = 'off';
                app.d10SpinnerLabel.Visible = 'off';
                app.d10Spinner.Value=0;
            end
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            %             selectedButton = app.ButtonGroup.SelectedObject.T;

            switch app.ButtonGroup.SelectedObject.Text
                case 'Enter DOF' %where "Button1" is the name of the first radio button
                    app.EnterDOFDropDown.Visible = 'on';
                    app.EnterDOFDropDownLabel.Visible = 'on';
                    app.PushButton.Visible = 'on';
                    app.PlotROBOTButton.Visible = 'on';
                    app.WorkEnvelopeButton.Visible = 'on';

                    app.ChooseRobotDropDown.Visible = 'off';
                    app.ChooseRobotDropDownLabel.Visible = 'off';
                    app.PushButton_2.Visible = 'off';
                    app.PlotROBOTButton_2.Visible = 'off';
                    app.WorkEnvelopeButton_2.Visible = 'off';

                case 'Choose Robot'
                    app.EnterDOFDropDown.Visible = 'off';
                    app.EnterDOFDropDownLabel.Visible = 'off';
                    app.PushButton.Visible = 'off';
                    app.PlotROBOTButton.Visible = 'off';
                    app.WorkEnvelopeButton.Visible = 'off';

                    app.ChooseRobotDropDown.Visible = 'on';
                    app.ChooseRobotDropDownLabel.Visible = 'on';
                    app.PushButton_2.Visible = 'on';
                    app.PlotROBOTButton_2.Visible = 'on';
                    app.WorkEnvelopeButton_2.Visible = 'on';
            end
            %
            %             if (selectedButton=='Enter DOF')
            %                 app.EnterDOFDropDown.Visible = 'on';
            %                 app.EnterDOFDropDownLabel.Visible = 'on';
            %                 app.PushButton.Visible = 'on';
            %
            %                 app.ChooseRobotDropDown.Visible = 'off';
            %                 app.ChooseRobotDropDownLabel.Visible = 'off';
            %                 app.PushButton_2.Visible = 'off';
            %
            %             elseif (selectedButton=="Choose Robot")
            %                 app.EnterDOFDropDown.Visible = 'off';
            %                 app.EnterDOFDropDownLabel.Visible = 'off';
            %                 app.PushButton.Visible = 'off';
            %
            %                 app.ChooseRobotDropDown.Visible = 'on';
            %                 app.ChooseRobotDropDownLabel.Visible = 'on';
            %                 app.PushButton_2.Visible = 'on';
            %             end
        end

        % Button pushed function: PushButton_2
        function PushButton_2Pushed(app, event)
            RobotType = app.ChooseRobotDropDown.Value;
            app.JointVariable.Visible = 'on';
            if (RobotType=="Cartesian (PPP)")
                DHTab = [90 1 0 90;90 1 0 -90;0 1 0 0];
                app.UITable.Data = DHTab;
                app.UITable.RowName = {'Joint-1','Joint-2','Joint-3'};
                app.UITable.ColumnName = {'Theta','d','a','Alpha'};
                app.Joint_1Type.Visible='on';
                app.Joint_2Type.Visible = 'on';
                app.Joint_3Type.Visible = 'on';
                app.Joint_4Type.Visible = 'off';
                app.Joint_5Type.Visible = 'off';
                app.Joint_6Type.Visible = 'off';
                app.Joint_7Type.Visible = 'off';
                app.Joint_8Type.Visible = 'off';
                app.Joint_9Type.Visible = 'off';
                app.Joint_10Type.Visible = 'off';

                app.d1Spinner.Visible = 'on'; app.d1SpinnerLabel.Visible = 'on';
                app.d2Spinner.Visible = 'on'; app.d2SpinnerLabel.Visible = 'on';
                app.d3Spinner.Visible = 'on'; app.d3SpinnerLabel.Visible = 'on';

                app.d4Spinner.Visible = 'off'; app.d4SpinnerLabel.Visible = 'off';
                app.d5Spinner.Visible = 'off'; app.d5SpinnerLabel.Visible = 'off';
                app.d6Spinner.Visible = 'off'; app.d6SpinnerLabel.Visible = 'off';
                app.d7Spinner.Visible = 'off'; app.d7SpinnerLabel.Visible = 'off';
                app.d8Spinner.Visible = 'off'; app.d8SpinnerLabel.Visible = 'off';
                app.d9Spinner.Visible = 'off'; app.d9SpinnerLabel.Visible = 'off';
                app.d10Spinner.Visible = 'off'; app.d10SpinnerLabel.Visible = 'off';

                app.Joint_1Type.Value = "Prismatic";
                app.Joint_2Type.Value = "Prismatic";
                app.Joint_3Type.Value = "Prismatic";

                %                 app.UIAxes.Visible = 'off';
                %                 app.UIAxes2.Visible = 'on';
                %                 img = imread("Cartesian.jpg");
                %                 imshow(img,'Parent',app.UIAxes2);

            elseif (RobotType=="Cylindrical (RPP)")
                DHTab = [0 1 0 0;0 1 0 -90;0 1 0 0];
                app.UITable.Data = DHTab;
                app.UITable.RowName = {'Joint-1','Joint-2','Joint-3'};
                app.UITable.ColumnName = {'Theta','d','a','Alpha'};
                app.Joint_1Type.Visible='on';
                app.Joint_2Type.Visible = 'on';
                app.Joint_3Type.Visible = 'on';
                app.Joint_4Type.Visible = 'off';
                app.Joint_5Type.Visible = 'off';
                app.Joint_6Type.Visible = 'off';
                app.Joint_7Type.Visible = 'off';
                app.Joint_8Type.Visible = 'off';
                app.Joint_9Type.Visible = 'off';
                app.Joint_10Type.Visible = 'off';

                app.d1Spinner.Visible = 'off'; app.d1SpinnerLabel.Visible = 'off';
                app.d2Spinner.Visible = 'on'; app.d2SpinnerLabel.Visible = 'on';
                app.d3Spinner.Visible = 'on'; app.d3SpinnerLabel.Visible = 'on';

                app.d4Spinner.Visible = 'off'; app.d4SpinnerLabel.Visible = 'off';
                app.d5Spinner.Visible = 'off'; app.d5SpinnerLabel.Visible = 'off';
                app.d6Spinner.Visible = 'off'; app.d6SpinnerLabel.Visible = 'off';
                app.d7Spinner.Visible = 'off'; app.d7SpinnerLabel.Visible = 'off';
                app.d8Spinner.Visible = 'off'; app.d8SpinnerLabel.Visible = 'off';
                app.d9Spinner.Visible = 'off'; app.d9SpinnerLabel.Visible = 'off';
                app.d10Spinner.Visible = 'off'; app.d10SpinnerLabel.Visible = 'off';

                app.Joint_1Type.Value = "Revolute";
                app.Joint_2Type.Value = "Prismatic";
                app.Joint_3Type.Value = "Prismatic";

                %                 app.UIAxes.Visible = 'off';
                %                 app.UIAxes2.Visible = 'on';
                %                 img = imread("Cylindrical.jpg");
                %                 imshow(img,'Parent',app.UIAxes2);
            end
        end

        % Button pushed function: PlotROBOTButton_2
        function PlotROBOTButton_2Pushed(app, event)

            %             app.UIAxes.Visible = 'on';
            %             app.UIAxes2.Visible = 'off';

            type = app.ChooseRobotDropDown.Value;
            DHTab = app.UITable.Data;
            if (type=="Cartesian (PPP)")
                V1 = app.d1Spinner.Value;
                th1 = DHTab(1,1); D1 = DHTab(1,2)+V1; L1 = DHTab(1,3); al1 = DHTab(1,4);
                V2 = app.d2Spinner.Value;
                th2 = DHTab(2,1); D2 = DHTab(2,2)+V2; L2 = DHTab(2,3); al2 = DHTab(2,4);
                V3 = app.d3Spinner.Value;
                th3 = DHTab(3,1); D3 = DHTab(3,2)+V3; L3 = DHTab(3,3); al3 = DHTab(3,4);
            elseif (type=="Cylindrical (RPP)")
                th1 = DHTab(1,1); D1 = DHTab(1,2);    L1 = DHTab(1,3); al1 = DHTab(1,4);
                V2 = app.d2Spinner.Value;
                th2 = DHTab(2,1); D2 = DHTab(2,2)+V2; L2 = DHTab(2,3); al2 = DHTab(2,4);
                V3 = app.d3Spinner.Value;
                th3 = DHTab(3,1); D3 = DHTab(3,2)+V3; L3 = DHTab(3,3); al3 = DHTab(3,4);
            end

            a1 = L1; alpha1 = al1; d1 = D1; theta1 = th1;  % link-1
            a2 = L2; alpha2 = al2; d2 = D2; theta2 = th2;  % link-2
            a3 = L3; alpha3 = al3; d3 = D3; theta3 = th3;  % link-3

            H0_1 = DH(theta1, d1, a1, alpha1);
            H1_2 = DH(theta2, d2, a2, alpha2);
            H2_3 = DH(theta3, d3, a3, alpha3);

            H0_2 = H0_1 * H1_2;
            H0_3 = H0_2 * H2_3;

            x1 = H0_1(1,4); x2 = H0_2(1,4); x3 = H0_3(1,4);
            y1 = H0_1(2,4); y2 = H0_2(2,4); y3 = H0_3(2,4);
            z1 = H0_1(3,4); z2 = H0_2(3,4); z3 = H0_3(3,4);


            if (type=="Cartesian (PPP)")
                % Link - 1
                plot3(app.UIAxes,[0 x1],[0 y1],[0 z1],'o-','linewidth',2)
                hold(app.UIAxes,'on')
                % Link - 2
                plot3(app.UIAxes,[x1 x2],[y1 y2],[z1 z2],'o-','linewidth',2)
                hold(app.UIAxes,'on')
                % Link - 3
                plot3(app.UIAxes,[x2 x3],[y2 y3],[z2 z3],'o-','linewidth',2)
                hold(app.UIAxes,'on')
                plot3(app.UIAxes,0,0,0,'s-','LineWidth',4)
                hold(app.UIAxes,"on")
                plot3(app.UIAxes,x1,y1,z1,'s-','LineWidth',4)
                hold(app.UIAxes,"on")
                plot3(app.UIAxes,x2,y2,z2,'s-','LineWidth',4)
                hold(app.UIAxes,"off")

            elseif (type=="Cylindrical (RPP)")
                % Link - 1
                plot3(app.UIAxes,[0 x1],[0 y1],[0 z1],'o-','linewidth',2)
                hold(app.UIAxes,'on')
                % Link - 2
                plot3(app.UIAxes,[x1 x2],[y1 y2],[z1 z2],'o-','linewidth',2)
                hold(app.UIAxes,'on')
                % Link - 3
                plot3(app.UIAxes,[x2 x3],[y2 y3],[z2 z3],'o-','linewidth',2)
                hold(app.UIAxes,'on')

                plot3(app.UIAxes,x1,y1,z1,'s-','LineWidth',4)
                hold(app.UIAxes,"on")
                plot3(app.UIAxes,x2,y2,z2,'s-','LineWidth',4)
                hold(app.UIAxes,"off")

            end

            xlabel(app.UIAxes,"X-axis")
            ylabel(app.UIAxes,"Y-axis")
            zlabel(app.UIAxes,"Z-axis")

            grid(app.UIAxes,"on")
            axis(app.UIAxes,[-(d1+d2+d3+2) (d1+d2+d3+2) -(d1+d2+d3+2) (d1+d2+d3+2) 0 (d1+d2+d3+2)])
            axis(app.UIAxes,'square')


            if (type=="Cartesian (PPP)")
                title(app.UIAxes,'Cartesian Robot');
                app.XEditField.Value = H0_3(1,4);
                app.YEditField.Value = H0_3(2,4);
                app.ZEditField.Value = H0_3(3,4);
                orientation_of_end_effector = H0_3(1:3,1:3);
                app.UITable2.Data = orientation_of_end_effector;
                DHTable = [th1 d1-V1 a1 al1;th2 d2-V2 a2 al2;th3 d3-V3 a3 al3;];
                app.UITable.Data = DHTable;
                app.UITable.ColumnName = {'Theta','d','a','Alpha'};
                app.UITable.RowName = {'Joint-1','Joint-2','Joint-3'};
            elseif (type=="Cylindrical (RPP)")
                title(app.UIAxes,'Cylindrical Robot');
                app.XEditField.Value = H0_3(1,4);
                app.YEditField.Value = H0_3(2,4);
                app.ZEditField.Value = H0_3(3,4);
                orientation_of_end_effector = H0_3(1:3,1:3);
                app.UITable2.Data = orientation_of_end_effector;
                DHTable = [th1 d1 a1 al1;th2 d2-V2 a2 al2;th3 d3-V3 a3 al3;];
                app.UITable.Data = DHTable;
                app.UITable.ColumnName = {'Theta','d','a','Alpha'};
                app.UITable.RowName = {'Joint-1','Joint-2','Joint-3'};
            end


            function [A] = DH(theta, d, a, alpha)

                A = [cosd(theta) -sind(theta)*(cosd(alpha)) sind(theta)*(sind(alpha)) a*cosd(theta);
                    sind(theta) cosd(theta)*(cosd(alpha)) -cosd(theta)*(sind(alpha))  a*sind(theta);
                    0               (sind(alpha))            (cosd(alpha))             d       ;
                    0                   0                         0                    1      ];
            end

        end

        % Button pushed function: WorkEnvelopeButton_2
        function WorkEnvelopeButton_2Pushed(app, event)
            type = app.ChooseRobotDropDown.Value;
            DHTab = app.UITable.Data;
            if (type=="Cartesian (PPP)")
                V1 = app.d1Spinner.Value;
                d1 = DHTab(1,2)+V1;
                V2 = app.d2Spinner.Value;
                d2 = DHTab(2,2)+V2;
                V3 = app.d3Spinner.Value;
                d3 = DHTab(3,2)+V3;

                x = [-d2    d2    d2    -d2    -d2;
                    -d2    d2    d2    -d2    -d2];
                y = [-d3    -d3    d3    d3    -d3;
                    -d3    -d3    d3    d3    -d3];
                z = [-d1    -d1    -d1    -d1    -d1;
                    d1     d1     d1     d1     d1];

                surf(app.UIAxes, x, y, z, 'FaceColor','g')       % Plot Cube
                hold(app.UIAxes,"on")
                patch(app.UIAxes,x', y', z', 'r')      % Make Cube Appear Solid
                hold(app.UIAxes,"off")
                axis(app.UIAxes,[ -(d2+2)  (d2+2)    -(d3+2)  (d3+2)    -(d1+2)  (d1+2)])
                grid(app.UIAxes,'on')
                xlabel(app.UIAxes,'x')
                ylabel(app.UIAxes,'y')
                zlabel(app.UIAxes,'z')
            elseif (type=="Cylindrical (RPP)")
                d1 = DHTab(1,2);
                V2 = app.d2Spinner.Value;
                d2 = DHTab(2,2)+V2;
                V3 = app.d3Spinner.Value;
                d3 = DHTab(3,2)+V3;

                r = d3;
                [X,Y,Z] = cylinder(r);
                Z = Z*(d1+d2);
                surf(app.UIAxes,X,Y,Z)
                axis(app.UIAxes,[ -(d3+3)  (d3+3)    -(d3+3)  (d3+3)    0  (d1+d2+2)])
                grid(app.UIAxes,'on')
                xlabel(app.UIAxes,'x')
                ylabel(app.UIAxes,'y')
                zlabel(app.UIAxes,'z')

            end
        end

        % Button pushed function: CLOSEButton
        function CLOSEButtonPushed(app, event)
            close(app.UIFigure)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {'A,B,C,D'};
            app.UITable.ColumnEditable = true;
            app.PushButton = uibutton(app.UIFigure, 'push');
            app.PushButton.ButtonPushedFcn = createCallbackFcn(app, @PushButtonPushed, true);
            app.PushButton.Text = 'Push';
            app.EnterDOFDropDownLabel = uilabel(app.UIFigure);
            app.EnterDOFDropDownLabel.Text = 'Enter DOF';
            app.EnterDOFDropDown = uidropdown(app.UIFigure);
            app.EnterDOFDropDown.Items = {'2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.EnterDOFDropDown.Value = '2';
            app.JointTypeDropDownLabel = uilabel(app.UIFigure);
            app.JointTypeDropDownLabel.Text = 'Joint Type';
            app.Joint_1Type = uidropdown(app.UIFigure);
            app.Joint_1Type.Items = {'Revolute', 'Prismatic'};
            app.Joint_1Type.ValueChangedFcn = createCallbackFcn(app, @Joint_1TypeValueChanged, true);
            app.Joint_1Type.Value = 'Revolute';
            app.Joint_2Type = uidropdown(app.UIFigure);
            app.Joint_2Type.Items = {'Revolute', 'Prismatic'};
            app.Joint_2Type.ValueChangedFcn = createCallbackFcn(app, @Joint_2TypeValueChanged, true);
            app.Joint_2Type.Value = 'Revolute';
            app.PlotROBOTButton = uibutton(app.UIFigure, 'push');
            app.PlotROBOTButton.ButtonPushedFcn = createCallbackFcn(app, @PlotROBOTButtonPushed, true);
            app.PlotROBOTButton.Text = 'Plot ROBOT';
            app.Joint_3Type = uidropdown(app.UIFigure);
            app.Joint_3Type.Items = {'Revolute', 'Prismatic'};
            app.Joint_3Type.ValueChangedFcn = createCallbackFcn(app, @Joint_3TypeValueChanged, true);
            app.Joint_3Type.Value = 'Revolute';
            app.Joint_4Type = uidropdown(app.UIFigure);
            app.Joint_4Type.Items = {'Revolute', 'Prismatic'};
            app.Joint_4Type.ValueChangedFcn = createCallbackFcn(app, @Joint_4TypeValueChanged, true);
            app.Joint_4Type.Value = 'Revolute';
            app.Joint_5Type = uidropdown(app.UIFigure);
            app.Joint_5Type.Items = {'Revolute', 'Prismatic'};
            app.Joint_5Type.ValueChangedFcn = createCallbackFcn(app, @Joint_5TypeValueChanged, true);
            app.Joint_5Type.Value = 'Revolute';
            app.Joint_6Type = uidropdown(app.UIFigure);
            app.Joint_6Type.Items = {'Revolute', 'Prismatic'};
            app.Joint_6Type.ValueChangedFcn = createCallbackFcn(app, @Joint_6TypeValueChanged, true);
            app.Joint_6Type.Value = 'Revolute';
            app.Joint_7Type = uidropdown(app.UIFigure);
            app.Joint_7Type.Items = {'Revolute', 'Prismatic'};
            app.Joint_7Type.ValueChangedFcn = createCallbackFcn(app, @Joint_7TypeValueChanged, true);
            app.Joint_7Type.Value = 'Revolute';
            app.Joint_8Type = uidropdown(app.UIFigure);
            app.Joint_8Type.Items = {'Revolute', 'Prismatic'};
            app.Joint_8Type.ValueChangedFcn = createCallbackFcn(app, @Joint_8TypeValueChanged, true);
            app.Joint_8Type.Value = 'Revolute';
            app.Joint_9Type = uidropdown(app.UIFigure);
            app.Joint_9Type.Items = {'Revolute', 'Prismatic'};
            app.Joint_9Type.ValueChangedFcn = createCallbackFcn(app, @Joint_9TypeValueChanged, true);
            app.Joint_9Type.Value = 'Revolute';
            app.Joint_10Type = uidropdown(app.UIFigure);
            app.Joint_10Type.Items = {'Revolute', 'Prismatic'};
            app.Joint_10Type.ValueChangedFcn = createCallbackFcn(app, @Joint_10TypeValueChanged, true);
            app.Joint_10Type.Value = 'Revolute';
            app.EndEffectorCoordinatesPanel = uipanel(app.UIFigure);
            app.EndEffectorCoordinatesPanel.Title = 'End-Effector Coordinates';
            app.XEditFieldLabel = uilabel(app.EndEffectorCoordinatesPanel);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.EndEffectorCoordinatesPanel, 'numeric');
            app.YEditFieldLabel = uilabel(app.EndEffectorCoordinatesPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.EndEffectorCoordinatesPanel, 'numeric');
            app.ZEditFieldLabel = uilabel(app.EndEffectorCoordinatesPanel);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.EndEffectorCoordinatesPanel, 'numeric');
            app.WorkEnvelopeButton = uibutton(app.UIFigure, 'push');
            app.WorkEnvelopeButton.ButtonPushedFcn = createCallbackFcn(app, @WorkEnvelopeButtonPushed, true);
            app.WorkEnvelopeButton.Text = 'Work Envelope';
            app.UITable2 = uitable(app.UIFigure);
            app.UITable2.ColumnName = '';
            app.UITable2.RowName = {};
            app.EndEffectorOrientationLabel = uilabel(app.UIFigure);
            app.EndEffectorOrientationLabel.Text = 'End-Effector Orientation';
            app.d1SpinnerLabel = uilabel(app.UIFigure);
            app.d1SpinnerLabel.Text = 'd1';
            app.d1Spinner = uispinner(app.UIFigure);
            app.JointVariable = uilabel(app.UIFigure);
            app.JointVariable.Text = 'Joint Variable';
            app.d2SpinnerLabel = uilabel(app.UIFigure);
            app.d2SpinnerLabel.Text = 'd2';
            app.d2Spinner = uispinner(app.UIFigure);
            app.d3SpinnerLabel = uilabel(app.UIFigure);
            app.d3SpinnerLabel.Text = 'd3';
            app.d3Spinner = uispinner(app.UIFigure);
            app.d4SpinnerLabel = uilabel(app.UIFigure);
            app.d4SpinnerLabel.Text = 'd4';
            app.d4Spinner = uispinner(app.UIFigure);
            app.d5SpinnerLabel = uilabel(app.UIFigure);
            app.d5SpinnerLabel.Text = 'd5';
            app.d5Spinner = uispinner(app.UIFigure);
            app.d6SpinnerLabel = uilabel(app.UIFigure);
            app.d6SpinnerLabel.Text = 'd6';
            app.d6Spinner = uispinner(app.UIFigure);
            app.d7SpinnerLabel = uilabel(app.UIFigure);
            app.d7SpinnerLabel.Text = 'd7';
            app.d7Spinner = uispinner(app.UIFigure);
            app.d8SpinnerLabel = uilabel(app.UIFigure);
            app.d8SpinnerLabel.Text = 'd8';
            app.d8Spinner = uispinner(app.UIFigure);
            app.d9SpinnerLabel = uilabel(app.UIFigure);
            app.d9SpinnerLabel.Text = 'd9';
            app.d9Spinner = uispinner(app.UIFigure);
            app.d10SpinnerLabel = uilabel(app.UIFigure);
            app.d10SpinnerLabel.Text = 'd10';
            app.d10Spinner = uispinner(app.UIFigure);
            app.ChooseRobotDropDownLabel = uilabel(app.UIFigure);
            app.ChooseRobotDropDownLabel.Text = 'Choose Robot';
            app.ChooseRobotDropDown = uidropdown(app.UIFigure);
            app.ChooseRobotDropDown.Items = {'Cartesian (PPP)', 'Cylindrical (RPP)'};
            app.ChooseRobotDropDown.Value = 'Cartesian (PPP)';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.EnterDOFButton = uiradiobutton(app.ButtonGroup);
            app.EnterDOFButton.Text = 'Enter DOF';
            app.EnterDOFButton.Value = true;
            app.ChooseRobotButton = uiradiobutton(app.ButtonGroup);
            app.ChooseRobotButton.Text = 'Choose Robot';
            app.PushButton_2 = uibutton(app.UIFigure, 'push');
            app.PushButton_2.ButtonPushedFcn = createCallbackFcn(app, @PushButton_2Pushed, true);
            app.PushButton_2.Text = 'Push';
            app.PlotROBOTButton_2 = uibutton(app.UIFigure, 'push');
            app.PlotROBOTButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlotROBOTButton_2Pushed, true);
            app.PlotROBOTButton_2.Text = 'Plot ROBOT';
            app.WorkEnvelopeButton_2 = uibutton(app.UIFigure, 'push');
            app.WorkEnvelopeButton_2.ButtonPushedFcn = createCallbackFcn(app, @WorkEnvelopeButton_2Pushed, true);
            app.WorkEnvelopeButton_2.Text = 'Work Envelope';
            app.CLOSEButton = uibutton(app.UIFigure, 'push');
            app.CLOSEButton.ButtonPushedFcn = createCallbackFcn(app, @CLOSEButtonPushed, true);
            app.CLOSEButton.Text = 'CLOSE';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RoboVizApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end